classdef MemoryNumber < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        GridLayout         matlab.ui.container.GridLayout
        LeftPanel          matlab.ui.container.Panel
        Panel              matlab.ui.container.Panel
        Label_2            matlab.ui.control.Label
        Slider_periodTime  matlab.ui.control.Slider
        Label_3            matlab.ui.control.Label
        Slider_numLength   matlab.ui.control.Slider
        NumLength_text     matlab.ui.control.Label
        PeriodTime_text    matlab.ui.control.Label
        RightPanel         matlab.ui.container.Panel
        Button_shadow      matlab.ui.control.StateButton
        TextArea           matlab.ui.control.TextArea
        Button_clear       matlab.ui.control.StateButton
        Button_start       matlab.ui.control.StateButton
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        NUM_Length  % 数字串长度
        numpool     % 数字池
        genNumStop  % Description
        seed        % 随机数初始值
        numinfo     
        startTrickcount  % Description
        num_length  % Description
    end
    
    methods (Access = public)
        
        function creatTimer(app,tim,period_time,num_length)
            Break = 0;
            tim.UserData = Break;
            tim.StartFcn = @TimerStart;
            tim.TimerFcn ={@TimerPerforme,app,num_length};
            tim.StopFcn  = @TimerCleanup;
            tim.Period = period_time;                       % 回调函数执行间隔
            tim.StartDelay = 0.1;                           % 大于或等于 0 的数字，用于以秒为单位指定启动计时器和首次执行 TimerFcn 中指定的函数之间的延迟
            tim.TasksToExecute = num_length;    % 指定定时器执行TimeFcn的执行次数
            tim.ExecutionMode = 'fixedSpacing';
            function TimerPerforme(~,event,app,NUMLENGTH)
                disp([event.Type,' Function starts to work at ',datestr(event.Data.time,'dd-mmm-yyyy HH:MM:SS.FFF')]);
                num = app.randomNum(app.seed);
                app.numpool(app.num_length+1) = num;
                app.num_length = app.num_length+1;
                app.outPut_Num(sprintf('%d',num));
                if app.num_length >= NUMLENGTH*app.startTrickcount
                    app.genNumStop = 1;
                end
            end
            
            function TimerStart(~,event)
                disp([event.Type,' Timer starts to work at ',datestr(event.Data.time,'dd-mmm-yyyy HH:MM:SS.FFF')]);
            end
            
            function TimerCleanup(~,event)
                disp([event.Type,' Timer ends to work at',datestr(event.Data.time,'dd-mmm-yyyy HH:MM:SS.FFF')])
            end
        end
        
        function outPut_Num(app,str)
            string = sprintf('%s  ', str);
            app.numinfo=horzcat(app.numinfo,string); % 水平串联数组
            app.TextArea.Value = app.numinfo;
        end
        
        function results = randomNum(app,seed)
            y = seed*(1-seed);
            results = mod(floor((y-floor(y))*1000),10);
%             fprintf(sprintf('%0.4f',y));
            app.seed = y;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.num_length = 0;
            app.genNumStop = 0;
            app.numinfo ='';
            app.startTrickcount =0;
            app.numpool = zeros(1,1);
            app.UIFigure.Name = 'Memeroy数字';
        end

        % Value changing function: Slider_numLength
        function Slider_numLengthValueChanging(app, event)
            changingValue = event.Value;
            changingValue = floor(changingValue);
            app.NumLength_text.Text = sprintf('%.0f',changingValue);
        end

        % Value changing function: Slider_periodTime
        function Slider_periodTimeValueChanging(app, event)
            changingValue = event.Value;
            app.PeriodTime_text.Text = sprintf('%.1fS',changingValue);
        end

        % Value changed function: Button_start
        function Button_startValueChanged(app, event)
            app.startTrickcount =app.startTrickcount+1;     
            app.NUM_Length = floor(app.Slider_numLength.Value);
            period_time = app.Slider_periodTime.Value;
            app.seed = rand(1);
            tim = timer;
            if period_time==0,period_time=0.05;end
            app.creatTimer(tim,period_time,app.NUM_Length);
            assignin('base',"tim",tim); 
            start(tim);
            while(app.genNumStop ~=1)
                drawnow;
            end
            app.genNumStop=0;
            app.Button_start.Value = 0;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {479, 479};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: Button_shadow
        function Button_shadowValueChanged(app, event)
            value = app.Button_shadow.Value;
            if (app.Button_start.Value ==0)
                if app.num_length >= app.NUM_Length
                    if value == 1
                        app.TextArea.Value='';
                        app.numinfo='';
                        app.Button_shadow.Text = '显示';
                    else
                        for i=1:app.num_length
                            app.outPut_Num(sprintf('%d',app.numpool(i)));
                             app.Button_shadow.Text = '遮挡';
                        end
                    end
                end
            else
                 app.Button_shadow.Value=0;
            end
        end

        % Value changed function: Button_clear
        function Button_clearValueChanged(app, event)
            value = app.Button_clear.Value;
            app.num_length = 0;
            app.startTrickcount =0;
            app.numinfo=' ';
            app.TextArea.Value='';
            app.Button_clear.Value=0;
            app.numpool = zeros(1,1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Panel = uipanel(app.LeftPanel);
            app.Panel.Title = '控制面板';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '时间器';
            app.Slider_periodTime = uislider(app.Panel);
            app.Slider_periodTime.Limits = [0 5];
            app.Slider_periodTime.ValueChangingFcn = createCallbackFcn(app, @Slider_periodTimeValueChanging, true);
            app.Slider_periodTime.Value = 1;
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '数串长度';
            app.Slider_numLength = uislider(app.Panel);
            app.Slider_numLength.Limits = [6 20];
            app.Slider_numLength.ValueChangingFcn = createCallbackFcn(app, @Slider_numLengthValueChanging, true);
            app.Slider_numLength.MinorTicks = [6 7 8 9 10 11 12 13 14 15 16 17 18 19 20];
            app.Slider_numLength.Value = 6;
            app.NumLength_text = uilabel(app.Panel);
            app.NumLength_text.Text = '6';
            app.PeriodTime_text = uilabel(app.Panel);
            app.PeriodTime_text.Text = '1S';
            app.RightPanel = uipanel(app.GridLayout);
            app.Button_shadow = uibutton(app.RightPanel, 'state');
            app.Button_shadow.ValueChangedFcn = createCallbackFcn(app, @Button_shadowValueChanged, true);
            app.Button_shadow.Text = '遮挡';
            app.TextArea = uitextarea(app.RightPanel);
            app.Button_clear = uibutton(app.RightPanel, 'state');
            app.Button_clear.ValueChangedFcn = createCallbackFcn(app, @Button_clearValueChanged, true);
            app.Button_clear.Text = '清空';
            app.Button_start = uibutton(app.RightPanel, 'state');
            app.Button_start.ValueChangedFcn = createCallbackFcn(app, @Button_startValueChanged, true);
            app.Button_start.Text = '开始';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MemoryNumber

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end