classdef ComSer2_tcpapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Tab_connectMonitor_UI           matlab.ui.container.Tab
        Panel                           matlab.ui.container.Panel
        IPLabel                         matlab.ui.control.Label
        IPdisplay                       matlab.ui.control.EditField
        Button_connect                  matlab.ui.control.StateButton
        Label                           matlab.ui.control.Label
        ZhenNumdisplay                  matlab.ui.control.NumericEditField
        Label_2                         matlab.ui.control.Label
        Textconnect                     matlab.ui.control.TextArea
        Label_6                         matlab.ui.control.Label
        Portdisplay                     matlab.ui.control.NumericEditField
        clear1                          matlab.ui.control.Button
        Panel_2                         matlab.ui.container.Panel
        Label_3                         matlab.ui.control.Label
        TextArea_2                      matlab.ui.control.TextArea
        Button_decode                   matlab.ui.control.StateButton
        Button_file_select              matlab.ui.control.StateButton
        Mixframe2Label                  matlab.ui.control.Label
        Edit_filedirdisplay             matlab.ui.control.EditField
        E2Label                         matlab.ui.control.Label
        clear2                          matlab.ui.control.Button
        Tab_2                           matlab.ui.container.Tab
        Panel_3                         matlab.ui.container.Panel
        Label_10                        matlab.ui.control.Label
        TextArea_Loc                    matlab.ui.control.TextArea
        Button_clearLocinfo             matlab.ui.control.Button
        Button_StartLoc                 matlab.ui.control.StateButton
        Panel_4                         matlab.ui.container.Panel
        E2Label_3                       matlab.ui.control.Label
        Mixframe2Label_3                matlab.ui.control.Label
        Edit_filedirLoc                 matlab.ui.control.EditField
        Label_9                         matlab.ui.control.Label
        DropDown_Pf                     matlab.ui.control.DropDown
        Button_LocSet                   matlab.ui.control.StateButton
        Button_fileselect_Loc           matlab.ui.control.StateButton
        Panel_5                         matlab.ui.container.Panel
        UIPlotScatter                   matlab.ui.control.UIAxes
        Button_5                        matlab.ui.control.Button
        Button_4                        matlab.ui.control.Button
        Button_AssigninStruct           matlab.ui.control.StateButton
        Button_AssigninStruct_delete    matlab.ui.control.StateButton
        Tab_3                           matlab.ui.container.Tab
        CPIGroupPanel                   matlab.ui.container.Panel
        Button_CPIsource_select         matlab.ui.container.ButtonGroup
        InstantPositionButton           matlab.ui.control.ToggleButton
        InstantPositionButton_2         matlab.ui.control.ToggleButton
        InstantPositionLabel            matlab.ui.control.Label
        Edit_filedirStructData          matlab.ui.control.EditField
        Button_file_select_2            matlab.ui.control.StateButton
        E2CPILabel                      matlab.ui.control.Label
        Label_12                        matlab.ui.control.Label
        Panel_6                         matlab.ui.container.Panel
        Label_11                        matlab.ui.control.Label
        TextArea_CPIGroup               matlab.ui.control.TextArea
        Button_plotCPIGroupTrace        matlab.ui.control.Button
        Button_clear3_2                 matlab.ui.control.StateButton
        Button_ReturnServerceResult     matlab.ui.control.StateButton
        UIPlotTracePoints               matlab.ui.control.UIAxes
        Panel_set_tracepoint_parameter  matlab.ui.container.Panel
        VminEditFieldLabel              matlab.ui.control.Label
        VminEditField                   matlab.ui.control.NumericEditField
        VmaxEditFieldLabel              matlab.ui.control.Label
        VmaxEditField                   matlab.ui.control.NumericEditField
        VzminEditFieldLabel             matlab.ui.control.Label
        VzminEditField                  matlab.ui.control.NumericEditField
        VzmaxEditFieldLabel             matlab.ui.control.Label
        VzmaxEditField                  matlab.ui.control.NumericEditField
        xEditFieldLabel                 matlab.ui.control.Label
        xEditField                      matlab.ui.control.NumericEditField
        yEditFieldLabel                 matlab.ui.control.Label
        yEditField                      matlab.ui.control.NumericEditField
        zEditFieldLabel                 matlab.ui.control.Label
        zEditField                      matlab.ui.control.NumericEditField
        Button_6                        matlab.ui.control.Button
        Tab_4                           matlab.ui.container.Tab
        Panel_7                         matlab.ui.container.Panel
        E2CPILabel_2                    matlab.ui.control.Label
        Mixframe2Label_4                matlab.ui.control.Label
        Edit_filedirLoc_2               matlab.ui.control.EditField
        Label_13                        matlab.ui.control.Label
        DropDownNumSignalState          matlab.ui.control.DropDown
        Button_MoveStateLocSet          matlab.ui.control.StateButton
        Button_fileselect_Loc_2         matlab.ui.control.StateButton
        Label_15                        matlab.ui.control.Label
        DropDownNumFrameInState         matlab.ui.control.DropDown
        Button_deleteCurrentState       matlab.ui.control.StateButton
        CheckClearAllStation            matlab.ui.control.CheckBox
        Panel_8                         matlab.ui.container.Panel
        UIPlotMoveStateTrace            matlab.ui.control.UIAxes
        Button_MoveStatePlotclear       matlab.ui.control.Button
        Panel_9                         matlab.ui.container.Panel
        Label_14                        matlab.ui.control.Label
        TextArea_MoveStateLoc           matlab.ui.control.TextArea
        Button_clearMoveStateLocinfo    matlab.ui.control.Button
        Button_StartMoveStateLoc        matlab.ui.control.StateButton
        Panel_SetSubstractClassParameter  matlab.ui.container.Panel
        yaEditFieldLabel                matlab.ui.control.Label
        yaEditField                     matlab.ui.control.NumericEditField
        ybEditFieldLabel                matlab.ui.control.Label
        ybEditField                     matlab.ui.control.NumericEditField
        K_upEditFieldLabel              matlab.ui.control.Label
        K_upEditField                   matlab.ui.control.NumericEditField
        K_downEditFieldLabel            matlab.ui.control.Label
        K_downEditField                 matlab.ui.control.NumericEditField
        rEditFieldLabel                 matlab.ui.control.Label
        rEditField                      matlab.ui.control.NumericEditField
        yb15yaLabel                     matlab.ui.control.Label
        mLabel                          matlab.ui.control.Label
        mLabel_2                        matlab.ui.control.Label
        mLabel_3                        matlab.ui.control.Label
        Button_PlotMoveStateLocResult   matlab.ui.control.Button
        Button_MoveStateAssigninStruct  matlab.ui.control.StateButton
        Button_MoveStateAssigninStruct_delete  matlab.ui.control.StateButton
        Tab_RDplot_UI                   matlab.ui.container.Tab
        UIPlotRD                        matlab.ui.control.UIAxes
        Panel_RD                        matlab.ui.container.Panel
        E2Label_2                       matlab.ui.control.Label
        Mixframe2Label_2                matlab.ui.control.Label
        Edit_filedirdeocde              matlab.ui.control.EditField
        Button_fileselect_decode        matlab.ui.control.StateButton
        Label_7                         matlab.ui.control.Label
        DropRDorder                     matlab.ui.control.DropDown
        Button_set                      matlab.ui.control.StateButton
        Button_plotRD                   matlab.ui.control.Button
        Label_8                         matlab.ui.control.Label
        TextArea_RDinfo                 matlab.ui.control.TextArea
        Button_clear3                   matlab.ui.control.StateButton
        CheckBox                        matlab.ui.control.CheckBox
        Button_PlotRdclear              matlab.ui.control.Button
    end

    
    properties (Access = public)
        cellArrayText_connect %  字符向量数组 连接状态信息 % Description
        cellArrayText_decoder %  字符向量数组 帧解析状态信息 % Description
        cellArrayText_Rd      %  字符向量数组 帧解析状态信息 % Description
        cellArrayText_ScatterLoc
        cellArrayText_CPIGroup      % Description
        cellArrayText_MoveTargetLoc % Description
                     Bytedata %  存储接收的字节数据
                     framenum %  接收帧数量
                     framcount%  接收帧计数
                     issuccess%  接收帧数量是否满足需求  
                     issetRD  %  距离谱选择是否设置
                     issetLOC %  升空散射体辅助定位设置
                 Mixframe2_RD %  距离多普勒图UI 维护的混合数据结果帧
          Mixframe2_ScatteLoc %  升空散射体辅助定位UI 维护的混合数据结果帧
            ScatterLoc_Struct %  升空散射体辅助定位UI 维护的实施结构体点迹分选用
            ScatterLocUI_flagQin
              LOCUI_CPI_ORDER %  记录升空散射体辅助定位界面CPI序号
                Section_order %  距离多普勒图UI 维护的Mixframe2_RD中的扇区序号
             scatterloc_result%  散射体辅助定位UI维护的定位结果  
             MoveTarget_result%  移动单站定位UI维护的定位结果
          FileInstantPosition %  点迹分选与滤波UI维护的多CPI定位结果from文件
          LOCUIInstantPosition%  点迹分选与滤波UI维护的多CPI定位结果fromLOCUI
          TraceGroupArray_cell%  点迹分选与滤波UI维护的用于点迹分选定位结果元胞矩阵
          TraceGroup_Struct   %  Description
          MoverTargetcell_cell% 存储读取到的多站多帧解析帧数据
          MoverTargetcell_index 
          MoveTarget_struct   % 用于向点迹分选区输送数据结构体
          MoveTarget_trace    % Description
              is_SetMoveTarget


    end
    
    methods (Access = public)
        
        function outPut(app,str)
            string = sprintf('%s\n', str); % 将数据格式化为字符串或字符向量
            app.cellArrayText_connect=horzcat(app.cellArrayText_connect,string); % 水平串联数组
            app.Textconnect.Value = app.cellArrayText_connect; % 给TextArea赋值
        end
        function outPut_decode(app,str)
            string = sprintf('%s\n', str); % 将数据格式化为字符串或字符向量
            app.cellArrayText_decoder=horzcat(app.cellArrayText_decoder,string); % 水平串联数组
            app.TextArea_2.Value = app.cellArrayText_decoder; 
        end
        function outPut_RD(app,str)
            string = sprintf('%s\n', str); % 将数据格式化为字符串或字符向量
            app.cellArrayText_Rd=horzcat(app.cellArrayText_Rd,string); % 水平串联数组
            app.TextArea_RDinfo.Value = app.cellArrayText_Rd;
        end
        function outPut_ScatterLoc(app,str)
            string = sprintf('%s\n', str); % 将数据格式化为字符串或字符向量
            app.cellArrayText_ScatterLoc=horzcat(app.cellArrayText_ScatterLoc,string); % 水平串联数组
            app.TextArea_Loc.Value = app.cellArrayText_ScatterLoc;
        end
        function outPut_CPIGroupTrace(app,str)
            string = sprintf('%s\n', str); % 将数据格式化为字符串或字符向量
            app.cellArrayText_CPIGroup=horzcat(app.cellArrayText_CPIGroup,string); % 水平串联数组
            app.TextArea_CPIGroup.Value = app.cellArrayText_CPIGroup;
        end
        function outPut_MoveTargetLoc(app,str)
            string = sprintf('%s\n',str);
            app.cellArrayText_MoveTargetLoc=horzcat(app.cellArrayText_MoveTargetLoc,string);
            app.TextArea_MoveStateLoc.Value = app.cellArrayText_MoveTargetLoc;
        end
        function availableDate(app,tcp,sigframe_length)
            avail_length=sigframe_length;    %%有效帧的长度
            content_start = 85;  content_end=170;
            count = app.framcount;
            data=uint8(fread(tcp,avail_length,'uint8'));
            
            %%% 调试时用于保存读取到的帧 %%
%             new_dir = sprintf('E:\\混合数据帧2\\Mixframe2_%s.bin',datestr(now,'yyyy_mm_dd_HH_MM_SS_fff'));
%             statues = copyfile('E:\混合数据帧2\Mixframe2.bin',new_dir);
%             filename = new_dir;
%             if statues ==1
%             fileId = fopen(filename,'w');
%             fwrite(fileId,data,'uint8');
%             fclose(fileId);
%             end
            %%%
            
            % 测试计算服务器返回给监视服务器数据
            fileId =fopen('D:\ZZJ_项目\升空散射体-计算服务器\SigZhen_Mat.bin','r');
            Returnframe2=fread(fileId,'single');
            fclose(fileId);
            assignin("base","data",Returnframe2);
            headframe = zeros(1,10);                               % 与监视服务器连接的访问帧
            headframe(1) = 221;                                    % DD
            headframe(2) = 204;                                    % CC
            headframe(3) = 187;                                    % BB
            headframe(4) = 170;                                    % AA
            headframe(5) = 14;                                     % 524
            headframe(6) = 2;                                      % 
            headframe(9) = 85;                                     % 0x55
            headframe(10) = 42;                                    % 66
            %
            
            frame_part1 = sum(data(1:4));   % DDCCBBAA
            if frame_part1 == 782
                frame_part2 =167060;%data(5)+data(6)*2^8+data(7)*2^16+data(8)*2^32;
                if frame_part2 == 167060
                    if data(10) ==16 && data(9) == content_start && data(9+167059) == content_end
                        app.Bytedata{1,count+1} = data(1:avail_length);
                        app.framcount =app.framcount+1;
                        %%% 存储帧文件 %%%
                        new_dir = sprintf('E:\\混合数据帧2\\Mixframe2_%s.bin',datestr(now,'yyyy_mm_dd_HH_MM_SS_fff'));
                        statues = copyfile('E:\混合数据帧2\Mixframe2.bin',new_dir);
                        filename = new_dir;
                        if statues ==1
                            fileId = fopen(filename,'w');
                            fwrite(fileId,data,'uint8');
                            fclose(fileId);
                        end
                        %%%% 
                        app.outPut(new_dir);
                        pause(0.0005);       %等待打印0.5ms
                       %%% 收到成功后 测试返回数据给监视服务器
%                         fwrite(tcp,headframe,'uint8'); 
%                         fwrite(tcp,Returnframe2,'single');
%                         pause(0.005);
                       %%%
                    end
                end
            end
        end
        
        function S_xyz = LBH_XYZ(~,coordinate_LBH,cen_state)
            % 施放升空散射体 _S大地坐标转全局标准站心
            % coordinate_LBH各个站位经纬度坐标，数据类型：矩阵  列：数据维
            % cen_state 参考站心 L0_B0_H0 1*3维
            
            shape=size(coordinate_LBH); %获取矩阵维数，shape(2)记录列数
            L0=cen_state(1); B0=cen_state(2); H0=cen_state(3);
            R=6372566;  %地球半径
            S_xyz=zeros(shape(1),shape(2));
            for col=1:shape(2)
                LBH=coordinate_LBH(:,col);
                S_xyz(1,col)=(R+LBH(3))*cos(LBH(2))*sin(LBH(1)-L0);
                S_xyz(2,col)=(R+LBH(3))*sin(LBH(2))*cos(B0)-(R+LBH(3))*cos(LBH(2))*sin(B0)*cos(LBH(1)-L0);
                S_xyz(3,col)=(R+LBH(3))*cos(LBH(2))*cos(B0)*cos(LBH(1)-L0)+(R+LBH(3))*sin(LBH(2))*sin(B0)-R-H0;
            end
           % 截胡
%              S_xyz = [300,70,50     
%                       -400 70 60    
%                        5 -100 50 ]';
             S_xyz = coordinate_LBH;   % 因为自制的解析帧存储的是站心坐标
           %
        end
        function [Struct_data,isdecode]=decoder(app,str,filename)
            try
                fileId =fopen(str,'r');
                ByteDate_inital = fread(fileId,'uint8');
                fclose(fileId);                                            %% 读取耗时2ms
                ByteDate = uint8(ByteDate_inital(9:end)');
                [~,len]=size(ByteDate) ;
            catch
                len = 0;
            end
                isdecode = 0;
                Struct_data = 0;
              if len==167060
                %%%%% 全部解帧耗时0.175s = 175 ms
                Struct_data=cell(1,81);            %% 数据存放器
                Struct_data{1,1} = ByteDate(1,1);  %% 帧头
                Struct_data{1,2} = ByteDate(1,2);  %% 功能码
                %%%中心频率解析%%%
                zhong_pinInteger=double(typecast(ByteDate(1,3:4),'uint16'));              %% 中心频率整数部分
                zhong_pinXiaoshu=double(typecast(ByteDate(1,5:6),'uint16'))*2^-16;        %% 中心频率小数部分
                Struct_data{1,3}=zhong_pinInteger+zhong_pinXiaoshu;                       %% 中心频率
                %%%带宽编号%%%
                Order_num = typecast(ByteDate(1,7),'uint8');                  %%带宽编号
                Struct_data{1,4} = Order_num;
                %%%%时间解析%%%%
                Struct_data{1,5}=typecast(ByteDate(1,8:9),'uint16');   %% 年
                Struct_data{1,6}=typecast(ByteDate(1,10),'uint8');     %% 月
                Struct_data{1,7}=typecast(ByteDate(1,11),'uint8');     %% 日
                Struct_data{1,8}=typecast(ByteDate(1,12),'uint8');     %% 时
                Struct_data{1,9}=typecast(ByteDate(1,13),'uint8');     %% 分
                Struct_data{1,10}=typecast(ByteDate(1,14),'uint8');    %% 秒
                Struct_data{1,11}=typecast(ByteDate(1,15:18),'uint32');%% 高精度时标
                %%%%UCA站址信息解析%%%%
                jindu_zheng=typecast(ByteDate(1,20),'int8');                          %% 读取经度整数
                jindu_xiaoshu=typecast(ByteDate(1,21:22),'uint16');                   %% 读取经度小数
                widu_zheng=typecast(ByteDate(1,23),'int8');                           %% 读取纬度整数
                widu_xiaoshu=typecast(ByteDate(1,24:25),'uint16');                    %% 读取纬度小数
                Gaodu =typecast(ByteDate(1,26:27),'int16');                           %% 读取高度整数
                jindu=double(jindu_zheng)+(double(jindu_xiaoshu)/2^16);
                widu=double(widu_zheng)+(double(widu_xiaoshu)/2^16);
                Struct_data{1,12}= jindu;                                              %% 经度
                Struct_data{1,13}= widu;                                               %% 纬度
                Struct_data{1,14}= Gaodu;                                              %% 高度
                jiao_fangxiang = typecast(ByteDate(1,28:29),'uint16');                 %% 航向角整数部分
                jiao_fangXiao = typecast(ByteDate(1,30),'uint8');                      %% 航向角小数部分 0-9
                Struct_data{1,15} = double(jiao_fangxiang)+(jiao_fangXiao)/2^4;        %% 航向角 精确到后0.1°  0-360
                jiao_fuyang = typecast(ByteDate(1,31),'int8');                         %% 俯仰角整数部分
                jiao_fuyangX = typecast(ByteDate(1,32),'uint8');                       %% 俯仰角小数部分 0-9
                Struct_data{1,16} = double(jiao_fuyang)+double(jiao_fuyangX)/2^4;      %% 俯仰角 精确到后0.1°-90-90
                %%%%UCA磁场信息解析%%%%
                magnetic_x = typecast(ByteDate(1,33:34),'int16');
                magnetic_xXiao = typecast(ByteDate(1,35),'uint8');
                if magnetic_x < 0
                    magnetic_xXiao=magnetic_xXiao*-1;
                end
                magnetic_X = double(magnetic_x)+double(magnetic_xXiao)/2^4;
                magnetic_y = typecast(ByteDate(1,36:37),'int16');
                magnetic_yXiao = typecast(ByteDate(1,38),'uint8');
                if magnetic_y < 0
                    magnetic_yXiao=magnetic_yXiao*-1;
                end
                magnetic_Y = double(magnetic_y)+double(magnetic_yXiao)/2^4;
                magnetic_z = typecast(ByteDate(1,39:40),'int16');
                magnetic_zXiao = typecast(ByteDate(1,41),'uint8');
                if magnetic_z < 0
                    magnetic_zXiao=magnetic_zXiao*-1;
                end
                magnetic_Z = double(magnetic_z)+double(magnetic_zXiao)/2^4;
                Struct_data{1,17} = magnetic_X;                                       %% 磁场分量x
                Struct_data{1,18} = magnetic_Y;                                       %% 磁场分量y
                Struct_data{1,19} = magnetic_Z;                                       %% 磁场分量z
                %%%升空散射体Q信息解析%%%
                NumQ = typecast(ByteDate(1,42),'uint8');
                Struct_data{1,20} = NumQ;                                                %% 散射体个数
                index = 1;
                for i=43:20:103
                    Struct_data{1,20+index} = typecast(ByteDate(1,i:i+3),'single');      %% 读取经度整数
                    Struct_data{1,20+index+1} = typecast(ByteDate(1,i+4:i+7),'single');  %% 读取纬度
                    Struct_data{1,20+index+2} = typecast(ByteDate(1,i+8:i+11),'single'); %% 读取高度
                    Struct_data{1,20+index+3} = typecast(ByteDate(1,i+12:i+15),'single');%% 读取速度
                    Struct_data{1,20+index+4} = typecast(ByteDate(1,i+16:i+19),'single');%% 读取方向
                    index = index+5;
                end
                %%%DOA信息解析%%%%
                DoA_num=typecast(ByteDate(1,123),'uint8');
                Struct_data{1,41} = DoA_num;                                                       %% DOA数据个数
                index =1;
                for i=124:4:148
                    Struct_data{1,41+index} = double(typecast(ByteDate(1,i:i+1),'uint16'))/2^3;     %% 方向角0-360
                    Struct_data{1,41+index+1}=double(typecast(ByteDate(1,i+2:i+3),'uint16'))/2^3;   %% 俯仰角0-90
                    index = index + 2;
                end
                %%%%升空散射体T信息解析%%%%%
                Num_T=typecast(ByteDate(1,152),'uint8');
                Struct_data{1,56} = Num_T;                                            %% 可侧向散射体个数
                
                index =1;
                for i=1:4
                    order=typecast(ByteDate(1,152+index),'uint8');                    %% 57-64
                    s_order=floor(double(order)/2^4);
                    doa_order=floor((double(order)/2^4-s_order)*2^4);
                    Struct_data{1,56+index} = s_order;                                %% 可测向升空散射体序号
                    Struct_data{1,56+index+1} = doa_order;                            %% DOA值序号
                    index = index+2;
                end
                %%%参考通道DOA解析%%%%%
                order=typecast(ByteDate(1,157),'uint8');
                Struct_data{1,65} = order;
                %%%协方差矩阵%%%
                Rss = zeros(8,8);
                index = 158;
                for i=1:8
                    for j=1:8
                        rel = typecast(ByteDate(1,index:index+3),'single');
                        img = typecast(ByteDate(1,index+4:index+7),'single');
                        Rss(i,j) = rel+1i*img;
                        index = index+8;
                    end
                end
                Struct_data{1,66} =Rss;
                %%%IQ信号%%%
                IQ = zeros(1,32768);
                index = 670;
                for i=1:32678
                    rel=double(typecast(ByteDate(1,index:index+1),'int16'));
                    img=double(typecast(ByteDate(1,index+2:index+3),'int16'));
                    IQ(1,i)=rel + 1i*img;
                    index = index+4;
                end
                Struct_data{1,67} = IQ;
                %%%十二扇区距离多普勒谱%%%
                Order_num =Struct_data{1,4};
                if Order_num==0
                    scampling=0.203125;  %%信号采样率2013.125ksps
                elseif Order_num==1
                    % CPI_SigFrequent(1,2)=3;       %%带宽3MHz
                    scampling=4.0625;  %%信号采样率4.0625Msps
                elseif Order_num==2
                    % CPI_SigFrequent(1,2)=5;       %%带宽5MHz
                    scampling=8.125;   %%信号采样率8.125Msps
                elseif Order_num==3
                    % CPI_SigFrequent(1,2)=10;       %%带宽10MHz
                    scampling=16.25;   %%信号采样率16.25Msps
                elseif Order_num==4
                    % CPI_SigFrequent(1,2)=30;       %%带宽30MHz
                    scampling=32.5;   %%信号采样率32.25Msps
                end
                if scampling ==32.5
                    l = 163; k=1;
                elseif scampling ==16.25
                    l = 82; k=2;
                elseif scampling ==8.125
                    l = 41; k=3;
                elseif scampling ==4.0625
                    l = 21; k=6;
                elseif scampling ==0.203125
                    l = 1;  k=113;
                end
                index = 131742;
                for i=68:79
                    temple=zeros(2*l+1,2*k+1);
                    for j=1:2*l+1
                        for jj=1:2*k+1                                   % 十二扇区矩阵
                            value1 = typecast(ByteDate(1,index:index+1),'uint16');
                            value2 = typecast(ByteDate(1,index+2),'uint8');
                            temple(j,jj) = double(value1)*2^-16+double(value2)*2^-24;
                        end
                        index = index +3;
                    end
                    Struct_data{1,i} = temple;
                end
                %%%校验码%%%%
                Struct_data{1,80} = typecast(ByteDate(1,167058:167059),'uint16');
                %%%帧尾%%%
                Struct_data{1,81} = typecast(ByteDate(1,167060),'uint8');
                %%Struct_data 数据类型全部转为double类型
                for i=1:81
                    Struct_data{i} = double(Struct_data{i});
                end
                
                file=split(filename,'.');
                name=sprintf('E:\\混合数据帧2解析\\Mixframe2_%s_decode.mat',file{1});
                save(name,'Struct_data');
                isdecode = 1;
              else
                app.outPut_decode('帧内容错误，请选择有效帧文件!');
                pause(0.00005)
              end
        end

        function creatTimer(app,t,tim,Timelong)
            Break = 0;
            tim.UserData = Break;
            tim.StartFcn = @TimerStart;
            tim.TimerFcn = {@TimerPerforme,app,t,tim};
            tim.StopFcn = @TimerCleanup;
            tim.Period = 1;                   %%回调函数执行间隔
            tim.StartDelay = 0.5;             %大于或等于 0 的数字，用于以秒为单位指定启动计时器和首次执行 TimerFcn 中指定的函数之间的延迟
            tim.TasksToExecute = Timelong;    %指定定时器执行TimeFcn的执行次数
            tim.ExecutionMode = 'fixedSpacing';
            function TimerPerforme(~,event,app,t,tim)
                disp([event.Type,' Function starts to work at ',datestr(event.Data.time,'dd-mmm-yyyy HH:MM:SS.FFF')]);
                byte=t.BytesAvailable;
                disp(byte)
                
%                 if app.Button_connect.Value ==0
%                     set(tim,'UserData',-1);
%                     stop(tim);
%                 else
%                 end
            end
            function TimerStart(~,event)
                disp([event.Type,' Timer starts to work at ',datestr(event.Data.time,'dd-mmm-yyyy HH:MM:SS.FFF')]);
            end
            function TimerCleanup(tim,event)
                disp([event.Type,' Timer ends to work at',datestr(event.Data.time,'dd-mmm-yyyy HH:MM:SS.FFF')])
                set(tim,'UserData',-1);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = '计算服务器二';
            app.cellArrayText_connect=sprintf('%s\n', '***连接状态信息提示***');  % 赋初值 
            app.cellArrayText_decoder=sprintf('%s\n', '***帧解析信息提示***'); % 赋初值 
            app.cellArrayText_Rd     =sprintf('%s\n','***绘制信息提示***');
            app.cellArrayText_ScatterLoc=sprintf('%s\n','***定位信息提示***');
            app.cellArrayText_CPIGroup=sprintf('%s\n','***点迹分选信息提示***');
            app.cellArrayText_MoveTargetLoc=sprintf('%s\n','***移动单站定位信息提示***');
            app.issetRD =0;
            app.issetLOC =0;
            app.Mixframe2_RD ={};
            app.Mixframe2_ScatteLoc ={};
            app.LOCUI_CPI_ORDER = 0;
            app.FileInstantPosition={};
            app.LOCUIInstantPosition{1}=0;    % 第1位记录从LocUI界面存储下来结构体数量
            app.scatterloc_result=[];
            app.MoveTarget_result=[];
            app.TraceGroup_Struct ={} ;       % 点迹分选最终回传给服务器的多目标混合结果帧
            app.MoveTarget_trace=[];          % 单站坐标轨迹
            app.MoverTargetcell_cell{1}=0;
            app.MoverTargetcell_index=0;
            app.is_SetMoveTarget = 0;
        end

        % Value changed function: Button_file_select
        function Button_file_selectValueChanged(app, event)
            value = app.Button_file_select.Value; 
            [file,path] = uigetfile('E:\混合数据帧2\*.bin');
            if path ~=0
              app.Edit_filedirdisplay.Value = file(11:end);
            end
            if app.Button_file_select.Value ==1
                app.Button_file_select.Value=0;
            end
        end

        % Value changed function: Button_connect
        function Button_connectValueChanged(app, event)
            value = app.Button_connect.Value;
            pause(0.00005);
          if value ==1
            Ip = app.IPdisplay.Value;
            Port = app.Portdisplay.Value;
            app.framenum = app.ZhenNumdisplay.Value;
            app.Textconnect.Value=app.cellArrayText_connect;       % 文本区域中的初始显示信息
            app.Button_connect.Enable = 'off';
            str = sprintf('连接监视服务器IP:%s 端口号:%d ',Ip,Port);
            app.outPut(str);
            pause(0.00005);          % 读取时间50us
            connect =0;
                                    %% 定义连接帧 %%
            connectframe = zeros(1,13);                               % 与监视服务器连接的访问帧
            connectframe(1) = 221;                                    % DD
            connectframe(2) = 204;                                    % CC
            connectframe(3) = 187;                                    % BB
            connectframe(4) = 170;                                    % AA
            connectframe(5) = 5;                                      % 5
            connectframe(9) = 51;                                     % 0x33
            connectframe(10) = 16;                                    % 0x10
            connectframe(12) = 2;                                     % 0x02

           try
               app.outPut('等待连接...');                              % 等待20s，20s若不成功自动断连
               pause(0.00005);
               t=tcpip(Ip,Port,'NetworkRole','client','Timeout',10);  % tcp 连接
               t.InputBuffersize=5e6;                                 % Timeout是等待读取或者写入的最大时间  接收通道最多容纳50帧   内存空间5M   一帧长度0.1M
               t.OutputBuffersize=2e6;                                % 必须在打开t之前设置buffer长度 发送通道最多容纳20帧    内存空间2M
               t.ByteOrder='littleEndian' ;
               assignin("base","t",t);
               fopen(t);
               pause(0.00005);                                        % 停顿50us
               fwrite(t,connectframe,'uint8');                        % 发送连接帧
           catch
                str_error = sprintf('连接超时!');
                app.outPut(str_error)
                pause(0.00005);
                connect =-1;
                app.Button_connect.Enable = 'on';
                app.Button_connect.Value=0;
           end
           

           if connect ==0    
               app.outPut('连接成功!');
               app.Button_connect.Text = '断开连接';
               pause(0.00005);
               tim = timer;
               app.creatTimer(t,tim,25);
               start(tim); 
               app.issuccess = 0;
               app.framcount = 0;
               app.Bytedata = cell(1,app.framenum);
               sigframe_length = 167068;
               while(true)
                   if t.BytesAvailable >= sigframe_length
                       app.availableDate(t,sigframe_length);
                   end
                   if app.framcount == app.framenum
                       app.issuccess =1;
                       break;
                   end
                   if tim.UserData==-1
                      delete(tim);
                      break;
                   end
               end
               if app.issuccess ==1
                   fclose(t);
                   delete(t);
                   str = sprintf('接收到%d帧！',app.framenum);
                   app.outPut(str);
                   pause(0.00005);         
                   app.outPut('自动断开连接！');
                   app.Button_connect.Text = '连接服务器';
                   app.Button_connect.Value =0;
               else
                   fclose(t);
                   delete(t);
                   str = sprintf('接收到%d帧！',app.framcount);
                   app.outPut(str);
                   pause(0.00005);
                   app.Button_connect.Text = '连接服务器';
                   app.outPut('断开连接！');
                   app.Button_connect.Value =0;
               end
           end
            
          end
          app.Button_connect.Enable = 'on';
            
        end

        % Button pushed function: clear1
        function clear1Pushed(app, event)
            app.Textconnect.Value='';
            app.cellArrayText_connect=sprintf('%s\n', '***连接状态信息提示***');
            assignin("base","data2",app.cellArrayText_connect);
            
        end

        % Button pushed function: clear2
        function clear2ButtonPushed(app, event)
            app.TextArea_2.Value='';
            app.cellArrayText_decoder=sprintf('%s\n', '***帧解析信息提示***');
            
        end

        % Value changed function: Button_decode
        function Button_decodeValueChanged(app, event)
            value = app.Button_decode.Value;
            firename = app.Edit_filedirdisplay.Value;
            if firename ==' '
                app.outPut_decode('请先选择文件');
                pause(0.00005);
                app.Button_decode.Value=0;
            else
                str = sprintf('E:\\混合数据帧2\\Mixframe2_%s',firename);
                app.outPut_decode(['选择帧',str]);
                pause(0.0005);               % 读取时间0.5ms
                app.outPut_decode('开始解析...');
                app.Button_decode.Enable=false;
                pause(0.00005);              % 读取时间0.05ms
                [Struct_data,isdecode]=app.decoder(str,firename);  %% 调用帧解析函数decoder
             if isdecode ==1
                app.outPut_decode('解析完成！');
                pause(0.00005);              % 读取时间0.05ms
                app.outPut_decode('解析结果保存至E:\混合数据帧2解析');
                app.outPut_decode('打印帧信息...');
                pause(0.05);                % 读取时间0.05s
                inf0 = Struct_data;
                str_time=sprintf('帧发送时间:%d-%d-%d-%d-%d-%d-%d',inf0{1,5},inf0{1,6}, ...
                                        inf0{1,7},inf0{1,8},inf0{1,9},inf0{1,10},inf0{1,11});
                app.outPut_decode(str_time);
                pause(0.05);                % 读取时间0.05s
                str_lbh=sprintf('站址经度：东经%f 纬度：北纬%f 高度：海平面以上%f米',inf0{1,12},inf0{1,13},inf0{1,14});
                app.outPut_decode(str_lbh);
                pause(0.05);                % 读取时间0.05s
                str_mag=sprintf('站址x轴磁场分量：%f y轴磁场分量：%f z轴磁场分量：%f',inf0{1,17},inf0{1,18},inf0{1,19});
                app.outPut_decode(str_mag);
                pause(0.05);                % 读取时间0.05s
                app.outPut_decode('打印完成！');
             else
                app.outPut_decode('解析终止！');    
             end
             app.Button_decode.Enable=true;
             app.Button_decode.Value=0;
            end
        end

        % Value changed function: Button_set
        function Button_setValueChanged(app, event)
            value = app.Button_set.Value;
            app.Section_order = str2double(app.DropRDorder.Value(1));
            file_main_name =app.Edit_filedirdeocde.Value;
          if (file_main_name ~=' ')
            file_name = sprintf('E:\\混合数据帧2解析\\Mixframe2_%s',file_main_name);
            str = sprintf('选择绘制:Mixframe2_%s文件中第%d扇区',file_main_name,app.Section_order);
            app.outPut_RD(str);
            pause(0.0005);
            app.Button_set.Value=0;
            app.Mixframe2_RD = load(file_name);              %% 加载帧解析文件
            app.Mixframe2_RD = app.Mixframe2_RD.Struct_data;
            app.issetRD=1;
            app.outPut_RD('设置成功！')
            pause(0.00005);
          else
              app.outPut_RD('设置失败！')
              pause(0.00005);
              app.outPut_RD('请选择有效混合数据解析帧2')
              pause(0.00005);
              app.Button_set.Value=0;
          end
        end

        % Value changed function: Button_fileselect_decode
        function Button_fileselect_decodeValueChanged(app, event)
            value = app.Button_fileselect_decode.Value;
            [file,path] = uigetfile('E:\混合数据帧2解析\*.mat');
            if path ~=0
                app.Edit_filedirdeocde.Value = file(11:end);
            end
            if app.Button_fileselect_decode.Value ==1
                app.Button_fileselect_decode.Value=0;
            end
            app.issetRD = 0;
        end

        % Value changed function: Button_clear3
        function Button_clear3ValueChanged(app, event)
            value = app.Button_clear3.Value;
            app.TextArea_RDinfo.Value='';
            app.cellArrayText_Rd=sprintf('%s\n', '***绘制信息提示***');
            app.Button_clear3.Value =0;
        end

        % Button pushed function: Button_plotRD
        function Button_plotRDPushed(app, event)
            if size(app.Mixframe2_RD,2)>1 && app.issetRD ==1
                scampling =app.Mixframe2_RD{3};
                if scampling ==32.5
                    l = 163; k=1;
                elseif scampling ==16.25
                    l = 82; k=2;
                elseif scampling ==8.125
                    l = 41; k=3;
                elseif scampling ==4.0625
                    l = 21; k=6;
                elseif scampling ==0.203125
                    l = 1;  k=113;
                end
                Num_Q = app.Mixframe2_RD{20};
                Num_T = app.Mixframe2_RD{56};
                
                %%  引入测试使用的RD仿真数据
                  RD_SIMULATION = cell(1,12);
%                   RD_SIMULATION = load('E:\混合数据帧2解析\RD_cell_simulation.mat');
%                   RD_SIMULATION = RD_SIMULATION.RD_cell;
                  for i=1:12
                      RD_SIMULATION{i} = app.Mixframe2_RD{67+i};
                  end
                %%
                
                app.outPut_RD('  ***打印绘制信息***  ');
                pause(0.0005);
                if Num_Q >0 && Num_T <=0
                   str =sprintf('定位情景2：释放散射体个数:%d,可测向散射体个数:%d',Num_Q,Num_T);
                   app.outPut_RD(str);
                elseif Num_Q>0 && Num_T>0
                    str =sprintf('定位情景3：释放散射体个数:%d,可测向散射体个数:%d',Num_Q,Num_T);
                    app.outPut_RD(str);
                else
                    str =sprintf('定位情景1：释放散射体个数:%d,可测向散射体个数:%d',Num_Q,Num_T);
                    app.outPut_RD(str);
                end
                  str =sprintf('开始绘制第%d扇区RD图....',app.Section_order); 
                app.outPut_RD(str);
                pause(0.0005);
                              %%% 绘制RD谱 %%%
                cla(app.UIPlotRD);
                app.UIPlotRD.XLim=[-l,l];
                app.UIPlotRD.YLim=[-k,k];
                [Y,X] =meshgrid(-k:k,-l:l);
                Rd_plot = RD_SIMULATION{1,app.Section_order};
                Rd_plot = abs(Rd_plot);
                surf(app.UIPlotRD,X,Y,abs(Rd_plot),'Edgecolor','none');
                view(app.UIPlotRD,0,90);
                app.outPut_RD('绘制完成！');
                pause(0.0005);
            else
                app.outPut_RD('请先设置！')
            end
        end

        % Value changed function: Button_fileselect_Loc
        function Button_fileselect_LocValueChanged(app, event)
            value = app.Button_fileselect_Loc.Value;
            [file,path] = uigetfile('E:\混合数据帧2解析\*.mat');
            if path ~=0
                app.Edit_filedirLoc.Value = file(11:end);
            end
            if app.Button_fileselect_Loc.Value ==1
                app.Button_fileselect_Loc.Value=0;
            end
        end

        % Value changed function: Button_LocSet
        function Button_LocSetValueChanged(app, event)
            value = app.Button_LocSet.Value;
            file_main_name =app.Edit_filedirLoc.Value;
            if (file_main_name ~=' ')
                file_name = sprintf('E:\\混合数据帧2解析\\Mixframe2_%s',file_main_name);
                str = sprintf('选择Mixframe2_%s文件,设置虚警率:%s',file_main_name,app.DropDown_Pf.Value);
                app.outPut_ScatterLoc(str);
                pause(0.0005);
                app.Button_LocSet.Value=0;
                app.Mixframe2_ScatteLoc = load(file_name);              %% 加载帧解析文件
                app.Mixframe2_ScatteLoc = app.Mixframe2_ScatteLoc.Struct_data;
                app.issetLOC=1;
                app.outPut_ScatterLoc('设置成功！')
                pause(0.00005);
                app.LOCUI_CPI_ORDER = app.LOCUI_CPI_ORDER+1;            %% CPI的序号由设置次数记录
                % 打印帧消息
                app.outPut_ScatterLoc('打印帧信息...');
                pause(0.05);                % 读取时间0.05s
                inf0 = app.Mixframe2_ScatteLoc;
                str_time=sprintf('帧发送时间:%d-%d-%d-%d-%d-%d-%d',inf0{1,5},inf0{1,6}, ...
                    inf0{1,7},inf0{1,8},inf0{1,9},inf0{1,10},inf0{1,11});
                app.outPut_ScatterLoc(str_time);
                pause(0.05);                % 读取时间0.05s
                str_lbh=sprintf('站址经度：东经%f 纬度：北纬%f 高度：海平面以上%f米',inf0{1,12},inf0{1,13},inf0{1,14});
                app.outPut_ScatterLoc(str_lbh);
                pause(0.05);                % 读取时间0.05s
                str_mag=sprintf('站址x轴磁场分量：%f y轴磁场分量：%f z轴磁场分量：%f',inf0{1,17},inf0{1,18},inf0{1,19});
                app.outPut_ScatterLoc(str_mag);
                pause(0.05);                % 读取时间0.05s
                app.outPut_ScatterLoc('打印完成！');
                %
                
                % 绘制散射体与站的布局方式
                cla(app.UIPlotScatter);
                Q_num = app.Mixframe2_ScatteLoc{20};
                scatter_LBH = zeros(3,Q_num);
                state_LBH = [app.Mixframe2_ScatteLoc{12},app.Mixframe2_ScatteLoc{13},app.Mixframe2_ScatteLoc{14}];
                inital =21;
                for i=1:Q_num
                  scatter_LBH(1,i)=app.Mixframe2_ScatteLoc{inital};
                  scatter_LBH(2,i)=app.Mixframe2_ScatteLoc{inital+1};
                  scatter_LBH(3,i)=app.Mixframe2_ScatteLoc{inital+2};
                  inital = inital+5;
                end
                S_xyz=app.LBH_XYZ(scatter_LBH,state_LBH);   % 散射体坐标转为站心坐标系
                scatter3(app.UIPlotScatter,S_xyz(1,:),S_xyz(2,:),S_xyz(3,:),'red',"filled","v");
                hold(app.UIPlotScatter,'on');
                sou = [0,0,0];
                scatter3(app.UIPlotScatter,0,0,0,'black','filled','^');    % 绘制站心标识默认原点
                for ii = 1:Q_num
                    text(app.UIPlotScatter,S_xyz(1,ii)+5,S_xyz(2,ii)+5,S_xyz(3,ii)+5,['S',num2str(ii)],'Color','k');
                end
                text(app.UIPlotScatter,sou(1,1)+7,sou(1,2)+7,sou(1,3)+7,'R','Color','k');
                % 绘制完成
            else
                app.outPut_ScatterLoc('设置失败！')
                pause(0.00005);
                app.outPut_ScatterLoc('请选择有效混合数据解析帧2')
                pause(0.00005);
                app.Button_LocSet.Value=0;
            end
        end

        % Value changed function: TextArea_RDinfo
        function TextArea_RDinfoValueChanged(app, event)
            value = app.TextArea_RDinfo.Value;
            
        end

        % Button pushed function: Button_clearLocinfo
        function Button_clearLocinfoPushed(app, event)
            app.TextArea_Loc.Value='';
            app.cellArrayText_ScatterLoc=sprintf('%s\n', '***定位信息提示***');
           
        end

        % Value changed function: Button_StartLoc
        function Button_StartLocValueChanged(app, event)
            value = app.Button_StartLoc.Value;
            Pf =str2double(app.DropDown_Pf.Value);
            Num_Q = app.Mixframe2_ScatteLoc{20};
            Num_T = app.Mixframe2_ScatteLoc{56};

           if app.issetLOC==1 || size(app.Mixframe2_ScatteLoc,2)>1
              app.outPut_ScatterLoc('启动定位 !');
              pause(0.00005);
              [StructDate,UI_locinf,flag_Qin,is_used]=Qin_computerservice(app.Mixframe2_ScatteLoc,Pf,0); % 启动定位
              app.ScatterLocUI_flagQin =flag_Qin;
              if flag_Qin ==2
                   str =sprintf('定位情景2：释放散射体个数:%d,可测向散射体个数:%d',Num_Q,Num_T);
                   % 定位过程相关信息输出  
                   app.outPut_ScatterLoc(str);
                   app.outPut_ScatterLoc(UI_locinf{2});                      
                   str_TDOA_AZ_FODA = UI_locinf{3};
%                    app.outPut_ScatterLoc('保存TDOA_Az_FDOA数据结构体至:')% 暂时不输出文件信息
%                    app.outPut_ScatterLoc(str_TDOA_AZ_FODA)
                   pause(0.00005);
                   %
                   if is_used ~=0
                       % 定位结果信息输出
                       loc_result = StructDate{11};
                       
                       assignin("base","loc_result",loc_result);
                       
                       app.scatterloc_result = loc_result;       % 用于画图
                       num_loc_result = size(loc_result,2);
                       str_loc_result = sprintf('当前CPI时刻总共%d组定位结果，打印...',num_loc_result);
                       app.outPut_ScatterLoc(str_loc_result)
                       for i=1:num_loc_result
                           str_position=sprintf('定位结果%d: [%0.2f, %0.2f, %0.2f]',i,loc_result(1,i),loc_result(2,i),loc_result(3,i));
                           app.outPut_ScatterLoc(str_position);
                           pause(0.00005);
                       end
                       app.ScatterLoc_Struct=StructDate;        % 点迹分选按钮触发后往点迹分选区加入
                       str2 = sprintf('E:\\混合数据帧2解析\\CPI时刻的瞬时定位结果\\定位情景2\\InstantPosition-%s',app.Edit_filedirLoc.Value);
                       save(str2,'StructDate');
                   else
                       app.outPut_ScatterLoc('当前CPI时刻总共 0 组定位结果!');
                   end
                   str_STRUCTDATA = UI_locinf{4};
%                    app.outPut_ScatterLoc('保存当前CPI瞬时定位结果数据结构体至:')
%                    app.outPut_ScatterLoc(str_STRUCTDATA)
                   pause(0.00005);
              else
                  str =sprintf('定位情景3：释放散射体个数:%d,可测向散射体个数:%d',Num_Q,Num_T);
                  app.outPut_ScatterLoc(str);
                  % 定位过程相关信息输出  
                  % UI_locinf 分支x (CFAR输出结果、提示信息、TDOA结构存储位置)*3 定位参数couple、结构体
                  app.outPut_ScatterLoc(UI_locinf{2});                      % 分支1
                  pause(0.00005)
                  if size(UI_locinf{5},2)>0
                      app.outPut_ScatterLoc(UI_locinf{5});                  % 分支2
                      pause(0.00005) 
                  end
                  if size(UI_locinf{8},2)>0
                      app.outPut_ScatterLoc(UI_locinf{8});                  % 分支3
                      pause(0.00005)
                  end
                  if is_used ~=0
                     % 定位结果信息输出
                      loc_result = StructDate{11};
                      app.scatterloc_result = loc_result;       % 用于画图
                      num_loc_result = size(loc_result,2);
                      str_loc_result = sprintf('当前CPI时刻总共%d组定位结果，打印...',num_loc_result);
                      app.outPut_ScatterLoc(str_loc_result)
                      for i=1:num_loc_result
                         str_position=sprintf('定位结果%d: [%0.2f, %0.2f, %0.2f]m',i,loc_result(1,i),loc_result(2,i),loc_result(3,i));
                         app.outPut_ScatterLoc(str_position);
                         pause(0.00005);
                      end
                      app.ScatterLoc_Struct=StructDate;        % 点迹分选按钮触发后往点迹分选区加入
                      
                      str2 = sprintf('E:\\混合数据帧2解析\\CPI时刻的瞬时定位结果\\定位情景3\\InstantPosition-%s',app.Edit_filedirLoc.Value);
                      save(str2,'StructDate');
                  else
                       app.outPut_ScatterLoc('当前CPI时刻总共 0 组定位结果!');
                  end
              end
              app.Button_StartLoc.Value =0;
           end
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            cla(app.UIPlotScatter);
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            if size(app.scatterloc_result,2)>0 && app.issetLOC==1
                scatter3(app.UIPlotScatter,app.scatterloc_result(1,:),app.scatterloc_result(2,:),app.scatterloc_result(3,:),'filled',"d","g")
                text(app.UIPlotScatter,app.scatterloc_result(1,1)+7,app.scatterloc_result(2,1)+7,app.scatterloc_result(3,1)+7,'T')
            end
            
        end

        % Selection changed function: Button_CPIsource_select
        function Button_CPIsource_selectSelectionChanged(app, event)
            selectedButton = app.Button_CPIsource_select.SelectedObject;
            if app.InstantPositionButton_2.Value == 1
                app.Button_file_select_2.Enable =false;
                str =sprintf('LOCUIInstantPosition: 包含%d组定位结果',app.LOCUIInstantPosition{1});
                app.outPut_CPIGroupTrace(str);
            else
                app.Button_file_select_2.Enable =true;
            end
        end

        % Value changed function: Button_file_select_2
        function Button_file_select_2ValueChanged(app, event)
            value = app.Button_file_select_2.Value;
            [file,path] = uigetfile('E:\混合数据帧2解析\CPI时刻的瞬时定位结果\*.*','MultiSelect',"on");
            

            if path ~=0
                for i=1:size(file,2)
                    app.outPut_CPIGroupTrace(file{i}(17:end));
                    pause(0.00005)
                end
                app.Edit_filedirStructData.Value = path(26:end);
                for i=1:size(file,2)
                    str =sprintf('%s%s',path,file{i});
                    Struct_File=load(str);
                    app.FileInstantPosition{i} = Struct_File.StructDate;
                    assignin("base","value",app.FileInstantPosition);
                end
            end
            if app.Button_file_select_2.Value ==1
                app.Button_file_select_2.Value=0;
            end

        end

        % Value changed function: Button_clear3_2
        function Button_clear3_2ValueChanged(app, event)
            value = app.Button_clear3_2.Value;
            app.TextArea_CPIGroup.Value = '';
            app.cellArrayText_CPIGroup=sprintf('***点迹分选信息提示***\n');
            app.Button_clear3_2.Value =0;
        end

        % Value changed function: Button_AssigninStruct
        function Button_AssigninStructValueChanged(app, event)
            value = app.Button_AssigninStruct.Value;
          if size(app.scatterloc_result,2)>0
            app.LOCUIInstantPosition{1}=app.LOCUIInstantPosition{1}+1;
            app.LOCUIInstantPosition{app.LOCUIInstantPosition{1}+1}=app.ScatterLoc_Struct;
            app.outPut_ScatterLoc(sprintf('CPI%d：定位结果矩阵加入至LOCUInstantPosition',app.LOCUI_CPI_ORDER));
            assignin("base","LOCUInstant",app.LOCUIInstantPosition)
          else
              app.outPut_ScatterLoc(sprintf('请加入有效的定位结果数据! '));
          end
          app.Button_AssigninStruct.Value=0;
        end

        % Button pushed function: Button_plotCPIGroupTrace
        function Button_plotCPIGroupTracePushed(app, event)

               if app.InstantPositionButton.Value == 1
                   CPI_num = size(app.FileInstantPosition,2);
                  if CPI_num <=1
                   app.outPut_CPIGroupTrace('启动点迹分选失败！，定位结果小于等于1');
                   return;
                  end
                  
                  for i=1:size(app.FileInstantPosition,2)
                   app.TraceGroupArray_cell{i} = app.FileInstantPosition{i}{11};
                  end
                   LastCPI_Struct= app.FileInstantPosition{1};
                   str = sprintf('E:\\混合数据帧2解析\\多目标定位结果数据帧2\\%sTargetLocation2-%0.3f-%0.3f-%d%d%d-%d%d%d.mat',app.Edit_filedirStructData.Value ...
                                                                            ,LastCPI_Struct{2}(1),LastCPI_Struct{2}(3)...
                                  ,LastCPI_Struct{1}(1),LastCPI_Struct{1}(2),LastCPI_Struct{1}(3),LastCPI_Struct{1}(4),LastCPI_Struct{1}(5),LastCPI_Struct{1}(6));
               else
                   CPI_num = app.LOCUIInstantPosition{1};
                   if CPI_num <=1
                       app.outPut_CPIGroupTrace('启动点迹分选失败！，定位结果小于等于1');
                       return;
                   end
                   
                   for i=2:(CPI_num+1)
                       app.TraceGroupArray_cell{i-1} = app.LOCUIInstantPosition{i}{11};
                   end
                   LastCPI_Struct = app.LOCUIInstantPosition{CPI_num+1};    % LOCUI第一位是帧数量
                   
                   str = sprintf('E:\\混合数据帧2解析\\多目标定位结果数据帧2\\定位情景%d\\TargetLocation2-%0.3f-%0.3f-%d%d%d-%d%d%d.mat',app.ScatterLocUI_flagQin ...
                                                                            ,LastCPI_Struct{2}(1),LastCPI_Struct{2}(3)...
                                  ,LastCPI_Struct{1}(1),LastCPI_Struct{1}(2),LastCPI_Struct{1}(3),LastCPI_Struct{1}(4),LastCPI_Struct{1}(5),LastCPI_Struct{1}(6));
               end
               MutilTargetLocResult = cell(1,16);
               for i=1:10
                   if i == 6
                       MutilTargetLocResult{6}=LastCPI_Struct{i}(1:2,1:6);  % UCA经纬度速度and磁场
%                      MutilTargetLocResult{6}(1,1:3)=[114.432868,30.516793,65];
                       MutilTargetLocResult{6}(1,1:6)=[114.432868,30.516793,65,0,0,0];
                       MutilTargetLocResult{7}=0;                           % 帧检测信息
                   elseif i==7
                       Q = LastCPI_Struct{7}(1,1);
                       MutilTargetLocResult{8} = LastCPI_Struct{7}(1,1);    % 升空散射体数量
                       MutilTargetLocResult{9} = zeros(4,8);
                       MutilTargetLocResult{9}(1:end,:) = LastCPI_Struct{7}(2:end,:);
                   elseif i==8
                       DOA_num = LastCPI_Struct{8}(1,1); 
                       MutilTargetLocResult{10} = DOA_num;    % DOA数量
                       MutilTargetLocResult{11} = zeros(7,3);
                       MutilTargetLocResult{11}(1:end,:) = LastCPI_Struct{8}(2:end,:);    % DOA
                   elseif i==9
                       T_num =  LastCPI_Struct{9}(1,1); 
                       MutilTargetLocResult{12} = T_num;    % 可测向T数量
                       MutilTargetLocResult{13} = zeros(4,5);
                       MutilTargetLocResult{13}(1:end,:) = LastCPI_Struct{9}(2:end,:);    % 测向
                   elseif i==10
                       MutilTargetLocResult{14}=LastCPI_Struct{i};
                   else
                       MutilTargetLocResult{i}=LastCPI_Struct{i};
                   end
               end
               point_nums= 0;
               for i=1:size(app.TraceGroupArray_cell,2)
                   point_nums = point_nums + size(app.TraceGroupArray_cell{i},2);
               end
               app.outPut_CPIGroupTrace(sprintf('总计%d个坐标点送入点迹分选与滤波！',point_nums));
               app.outPut_CPIGroupTrace('启动点迹分选！')
               % 点迹分选区 %
               Vmin = app.VminEditField.Value;
               Vmax = app.VmaxEditField.Value;
               Vzmin = app.VzminEditField.Value;
               Vzmax = app.VzmaxEditField.Value;
               detax = app.xEditField.Value;
               detay = app.yEditField.Value;
               detaz = app.zEditField.Value;
               CPI =1;
               Grade=2;
               K=3;
               
               assignin('base','locresult_cell',app.TraceGroupArray_cell)
               
               [postrace_result,vtrace_result,postrace_cell,vtrace_cell] = Qin_LocByTraceOfPoint(app.TraceGroupArray_cell,Vmin,Vmax,Vzmin,Vzmax ...
                   ,detax,detay,detaz,CPI,Grade,K,CPI_num);
               
               assignin('base','postrace_cell',postrace_cell);
               assignin('base','vtrace_cell',vtrace_cell);
               
               MutilTargetLocResult{15}=size(postrace_result,2);
               MutilTargetLocResult{16}=zeros(size(postrace_result,2),6);
               MutilTargetLocResult{16}(:,1:3) = postrace_result';
               MutilTargetLocResult{16}(:,4:6) =vtrace_result';
               assignin("base","MutilTargetLocResult",MutilTargetLocResult);
               % 坐标转换至WGS84
               
               %
               app.TraceGroup_Struct = MutilTargetLocResult;      % 保存至发送区域
               save(str,'MutilTargetLocResult');
               pause(0.00005);
               app.outPut_CPIGroupTrace(sprintf('分选结果：保留%d个位置点，形成%d条目标轨迹',size(postrace_result,2),size(postrace_cell,2)))
               app.outPut_CPIGroupTrace('打印分选结果...');
              for i=1:size(postrace_cell,2)
                  app.outPut_CPIGroupTrace(sprintf('轨迹%d：',i));
               for j=1:size(postrace_cell{i},2)
                   str_position=sprintf('  位置%d: [%0.2f, %0.2f, %0.2f]m',j,postrace_cell{i}(1,j),postrace_cell{i}(2,j),postrace_cell{i}(3,j));
                   app.outPut_CPIGroupTrace(str_position);
                   str_position=sprintf('  V%d: [%0.2f, %0.2f, %0.2f]m/s',j,vtrace_cell{i}(1,j),vtrace_cell{i}(2,j),vtrace_cell{i}(3,j));
                   app.outPut_CPIGroupTrace(str_position);
                   pause(0.00005);
               end
              end
               % 绘图
               cla(app.UIPlotTracePoints);
               Q_num = MutilTargetLocResult{8};
               scatter_LBH = zeros(3,Q_num);
               state_LBH = [MutilTargetLocResult{6}(1,1),MutilTargetLocResult{6}(1,2),MutilTargetLocResult{6}(1,3)];
               for i=1:Q_num
                   scatter_LBH(1,i)=MutilTargetLocResult{9}(i,1);
                   scatter_LBH(2,i)=MutilTargetLocResult{9}(i,2);
                   scatter_LBH(3,i)=MutilTargetLocResult{9}(i,3);
               end
               
               S_xyz=app.LBH_XYZ(scatter_LBH,state_LBH);   % 散射体坐标转为站心坐标系
               
               scatter3(app.UIPlotTracePoints,S_xyz(1,:),S_xyz(2,:),S_xyz(3,:),'red',"filled","v");
               hold(app.UIPlotTracePoints,'on');
               sou = [0,0,0];
               scatter3(app.UIPlotTracePoints,0,0,0,'black','filled','^');    % 绘制站心标识默认原点
               for ii = 1:Q_num
                   text(app.UIPlotTracePoints,S_xyz(1,ii)+5,S_xyz(2,ii)+5,S_xyz(3,ii)+5,['S',num2str(ii)],'Color','k');
               end
               text(app.UIPlotTracePoints,sou(1,1)+7,sou(1,2)+7,sou(1,3)+7,'R','Color','k');
               scatter3(app.UIPlotTracePoints,postrace_result(1,:),postrace_result(2,:),postrace_result(3,:),'filled',"d","g")
               
               %绘图结束
               app.outPut_CPIGroupTrace('结果保存至：')
               app.outPut_CPIGroupTrace(str);                    % str(1:29)只包含文件路径
               app.outPut_CPIGroupTrace('点迹分选完成！');
           
        end

        % Value changed function: Button_AssigninStruct_delete
        function Button_AssigninStruct_deleteValueChanged(app, event)
            value = app.Button_AssigninStruct_delete.Value;
            Assin_num =app.LOCUIInstantPosition{1};
            if Assin_num >0
               app.LOCUIInstantPosition{Assin_num+1}={};
               Assin_num=Assin_num-1;
               app.LOCUIInstantPosition{1}=Assin_num;
               str = sprintf('LOCUIInstantPosition删除最新结构体数据');
               app.outPut_ScatterLoc(str);
               str = sprintf('LOCUIInstantPosition剩余 %d 组结构体数据',Assin_num);
               app.outPut_ScatterLoc(str);
            else
                 app.outPut_ScatterLoc('LOCUIInstantPosition剩余0组结构体数据！')
            end
            app.Button_AssigninStruct_delete.Value=0;
        end

        % Value changed function: Button_ReturnServerceResult
        function Button_ReturnServerceResultValueChanged(app, event)
            value = app.Button_ReturnServerceResult.Value;
             pause(0.00005);
          if size(app.TraceGroup_Struct,2)<=1
             app.outPut_CPIGroupTrace('请先启动点迹分选');
             app.Button_ReturnServerceResult.Value=0;
             return ;
          end
          
          if value ==1
            Ip = app.IPdisplay.Value;
            Port = app.Portdisplay.Value;
            app.framenum = app.ZhenNumdisplay.Value;
            app.Textconnect.Value=app.cellArrayText_connect;       % 文本区域中的初始显示信息
            app.Button_ReturnServerceResult.Enable = 'off';
            app.outPut_CPIGroupTrace('启动连接...')
            str = sprintf('连接监视服务器IP:%s 端口号:%d ',Ip,Port);
            app.outPut_CPIGroupTrace(str);
            pause(0.00005);          % 读取时间50us
            connect =0;
                                    %% 定义连接帧 %%
            connectframe = zeros(1,13);                               % 与监视服务器连接的访问帧
            connectframe(1) = 221;                                    % DD
            connectframe(2) = 204;                                    % CC
            connectframe(3) = 187;                                    % BB
            connectframe(4) = 170;                                    % AA
            connectframe(5) = 5;                                      % 5
            connectframe(9) = 51;                                     % 0x33
            connectframe(10) = 16;                                    % 0x10
            connectframe(12) = 2;                                     % 0x02
                                   %% 定义帧头信息 %%
               headframe = zeros(1,10);                               % 与监视服务器连接的访问帧
               headframe(1) = 221;                                    % DD
               headframe(2) = 204;                                    % CC
               headframe(3) = 187;                                    % BB
               headframe(4) = 170;                                    % AA
               headframe(5) = 14;                                     % 524
               headframe(6) = 2;                                      %
               headframe(9) = 85;                                     % 0x55
               headframe(10) = 102;                                   % 0x66

           try
               app.outPut_CPIGroupTrace('等待连接...');                              % 等待20s，20s若不成功自动断连
               pause(0.00005);
               t=tcpip(Ip,Port,'NetworkRole','client','Timeout',10);  % tcp 连接
               t.InputBuffersize=5e6;                                 % Timeout是等待读取或者写入的最大时间  接收通道最多容纳50帧   内存空间5M   一帧长度0.1M
               t.OutputBuffersize=2e6;                                % 必须在打开t之前设置buffer长度 发送通道最多容纳20帧    内存空间2M
               t.ByteOrder='littleEndian' ;
               assignin("base","t",t);
               fopen(t);
               pause(0.00005);                                        % 停顿50us
               fwrite(t,connectframe,'uint8');                        % 发送连接帧
           catch
                str_error = sprintf('连接超时!');
                app.outPut_CPIGroupTrace(str_error)
                pause(0.00005);
                connect =-1;
                app.Button_ReturnServerceResult.Enable = 'on';
                app.Button_ReturnServerceResult.Value=0;
           end
           assignin("base",'TraceGroup_Struct',app.TraceGroup_Struct);
           Length=0;
           if connect ==0    
               app.outPut_CPIGroupTrace('连接成功!');
               for i=1:16
                 row = size(app.TraceGroup_Struct{i},1);
                 cloumn =size(app.TraceGroup_Struct{i},2);
                 Length = Length+row* cloumn;
               end
               Length = Length*4+4;  % 111是除去0x55、0x66、0xAA 0xAA外纯32bit数据
               length = typecast(int32(Length),'uint8');                % 计算帧长度
               app.outPut_CPIGroupTrace(sprintf('待发送%d字节数据',Length))
               headframe(5:8)=length;
               fwrite(t,uint8(headframe),'uint8');
               for i=1:16
                   data = app.TraceGroup_Struct{i};
                  for j=1:size(data,1)
                    for k=1:size(data,2)
                      fwrite(t,single(data(j,k)),'single');
                    end
                  end
               end
               fwrite(t,uint8(170),"uint8");
               fwrite(t,uint8(170),"uint8");
               pause(0.0005);                                           % 暂停0.5ms
               app.outPut_CPIGroupTrace(sprintf('共发送%d字节数据',t.ValuesSent));
               app.Button_ReturnServerceResult.Text = '断开连接';
               pause(2);
               fclose(t);
               delete(t);
               pause(0.00005);
               
           end
            
          end
          app.Button_ReturnServerceResult.Enable = 'on';
          app.Button_ReturnServerceResult.Value = 0;
          app.Button_ReturnServerceResult.Text = '回传服务器';
        end

        % Value changed function: Button_fileselect_Loc_2
        function Button_fileselect_Loc_2ValueChanged(app, event)
           value = app.Button_fileselect_Loc_2.Value;
            [file,path] = uigetfile('E:\混合数据帧2解析\*.mat','MultiSelect',"on");
            framenum_set=str2double(app.DropDownNumFrameInState.Value);
            assignin("base","path",path);
            if path(1)~=0
                app.Edit_filedirLoc_2.Value = path(12:20);
                if  framenum_set> size(file,2)
                    app.outPut_MoveTargetLoc('当前站内解析帧数量不足，请重新选择!');
                    return ;
                end
                index = app.MoverTargetcell_index +1;
                app.MoverTargetcell_cell{index} = cell(1,framenum_set);
                for i=1:framenum_set
                    str =sprintf('%s%s',path,file{i});
                    cpidata=load(str);
                    app.MoverTargetcell_cell{index}{i}=cpidata.Struct_data;
                    app.outPut_MoveTargetLoc(sprintf('选择%s!',str));
                end
                app.outPut_MoveTargetLoc(sprintf('站%d输入数据成功，请设置!',index));
            else
                app.Edit_filedirLoc_2.Value=" ";
                app.outPut_MoveTargetLoc('路径为空!');
            end
            assignin("base",'Movertarget',app.MoverTargetcell_cell);
            app.Button_fileselect_Loc_2.Value =0;
        end

        % Button pushed function: Button_MoveStatePlotclear
        function Button_MoveStatePlotclearPushed(app, event)
            cla(app.UIPlotMoveStateTrace);
        end

        % Value changed function: Button_MoveStateLocSet
        function Button_MoveStateLocSetValueChanged(app, event)
           value = app.Button_MoveStateLocSet.Value;
           index =0;
           assignin("base","MoveTargetcell",app.MoverTargetcell_cell);
           framenum_set=str2double(app.DropDownNumFrameInState.Value);
           station_num=str2double(app.DropDownNumSignalState.Value);
           
           if app.is_SetMoveTarget == 1
               app.outPut_MoveTargetLoc("站数已满无法添加，请选择删除站信息或者清空所有站数据！");
               cla(app.UIPlotMoveStateTrace);
               for i=1:station_num
                   scatter(app.UIPlotMoveStateTrace,app.MoveTarget_trace(1,i),app.MoveTarget_trace(2,i),'filled',"^",'MarkerEdgeColor','k','MarkerFaceColor',[0,0.5,0.5]);
                   text(app.UIPlotMoveStateTrace,app.MoveTarget_trace(1,i)+1,app.MoveTarget_trace(2,i)-1,sprintf("站%d",i));
                   hold(app.UIPlotMoveStateTrace,'on');
               end
                plot(app.UIPlotMoveStateTrace,app.MoveTarget_trace(1,:),app.MoveTarget_trace(2,:),'--','Color','k');
               app.Button_MoveStateLocSet.Value =0;
               return ;
           end


           for i=1:size(app.MoverTargetcell_cell,2)
               last = size(app.MoverTargetcell_cell{i},2);
               if last == framenum_set
                   index =index+1;
               end
           end

           if index>0
              %
                currentCPI = app.MoverTargetcell_cell{index}{framenum_set};
                posR=zeros(3,1);
                for i=1:3
                     posR(i,1)=currentCPI{11+i};
                end
               app.MoveTarget_trace(:,index)=posR;

              %
              app.MoverTargetcell_index=app.MoverTargetcell_index+1;
              app.outPut_MoveTargetLoc(sprintf('设置第%d站位成功！',app.MoverTargetcell_index));
             if app.MoverTargetcell_index == station_num
              app.is_SetMoveTarget = 1;
              axis(app.UIPlotMoveStateTrace,[-80,100,-30,350])
              for i=1:station_num
                scatter(app.UIPlotMoveStateTrace,app.MoveTarget_trace(1,i),app.MoveTarget_trace(2,i),'filled',"^",'MarkerEdgeColor','k','MarkerFaceColor',[0,0.5,0.5]);
                text(app.UIPlotMoveStateTrace,app.MoveTarget_trace(1,i)+1,app.MoveTarget_trace(2,i)-1,sprintf("站%d",i));
                hold(app.UIPlotMoveStateTrace,'on');
              end
              plot(app.UIPlotMoveStateTrace,app.MoveTarget_trace(1,:),app.MoveTarget_trace(2,:),'--','Color','k');
              app.outPut_MoveTargetLoc(sprintf('设置成功，可启动定位'));
             end
           else
              app.outPut_MoveTargetLoc(sprintf('当前站未获得有效数量的帧数据！'));

           end
           app.Button_MoveStateLocSet.Value =0;
        end

        % Value changed function: Button_StartMoveStateLoc
        function Button_StartMoveStateLocValueChanged(app, event)
            value = app.Button_StartMoveStateLoc.Value;
            if app.is_SetMoveTarget <1
                app.outPut_MoveTargetLoc('站数据不足，请先设置！');
                app.Button_StartMoveStateLoc.Value = 0;
                return;
            else
                app.outPut_MoveTargetLoc('启动移动单站定位！');
                 ya = app.yaEditField.Value;   
                 yb = app.ybEditField.Value;
                 r=app.rEditField.Value;
                 k_up =app.K_upEditField.Value;
                 k_down = app.K_downEditField.Value;
                 Num_n = str2double(app.DropDownNumSignalState.Value);
                 Num_frame = str2double(app.DropDownNumFrameInState.Value);
                [StructDate,UI_info,flag_isUsed] = Qin1_computerseverce(app.MoverTargetcell_cell,Num_n,Num_frame,ya,yb,k_up,k_down,r);
                if flag_isUsed ~=0
                    % 定位结果信息输出
                    loc_result = StructDate{11};
                    app.MoveTarget_result = loc_result;       % 用于画图
                    num_loc_result = size(loc_result,2);
                    app.outPut_MoveTargetLoc(UI_info{1});
                    app.outPut_MoveTargetLoc(UI_info{2});
                    str_loc_result = sprintf('当前CPI时刻总共%d组定位结果，打印...',num_loc_result);
                    app.outPut_MoveTargetLoc(str_loc_result)
                    for i=1:num_loc_result
                        str_position=sprintf('定位结果%d: [%0.2f, %0.2f, %0.2f]米',i,loc_result(1,i),loc_result(2,i),loc_result(3,i));
                        app.outPut_MoveTargetLoc(str_position);
                        pause(0.00005);
                    end
                  app.MoveTarget_struct=StructDate;        % 点迹分选按钮触发后往点迹分选区加入
                else
                    app.outPut_MoveTargetLoc('当前CPI时刻总共 0 组定位结果!');
                end
                
                app.MoverTargetcell_index =0;  % 将站索引重新设置 
            end
            
            app.Button_StartMoveStateLoc.Value=0;
        end

        % Button pushed function: Button_clearMoveStateLocinfo
        function Button_clearMoveStateLocinfoPushed(app, event)
            app.TextArea_MoveStateLoc.Value = ' ';
            app.cellArrayText_MoveTargetLoc = sprintf('%s\n', '***移动单站定位信息提示***');
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            value = app.CheckBox.Value;
            cla(app.UIPlotRD);
            value2 = app.CheckBox.Value;
            if size(app.Mixframe2_RD,2)>1 && app.issetRD ==1
                scampling =app.Mixframe2_RD{3};
                if scampling ==32.5
                    l = 163; k=1;  dis=9.23;  f=991.82;
                elseif scampling ==16.25
                    l = 82; k=2;   dis=18.46;
                elseif scampling ==8.125      f=495.91;
                    l = 41; k=3;   dis=36.92;
                elseif scampling ==4.0625     f=247.96;
                    l = 21; k=6;   dis=73.84;
                elseif scampling ==0.203125   f=123.98;
                    l = 1;  k=113; dis=1476.92;  f=6.2;
                end             
                %%  引入测试使用的RD仿真数据
                  RD_SIMULATION = cell(1,12);
%                   RD_SIMULATION = load('E:\混合数据帧2解析\RD_cell_simulation.mat');
%                   RD_SIMULATION = RD_SIMULATION.RD_cell;
                  for i=1:12
                      RD_SIMULATION{i} = app.Mixframe2_RD{67+i};
                  end
                %%
                              %%% 绘制RD谱 %%%
                app.UIPlotRD.XLim=[-l,l];
                app.UIPlotRD.YLim=[-k,k];
                [Y,X] =meshgrid(-k:k,-l:l);
                Rd_plot = RD_SIMULATION{1,app.Section_order};
               assignin("base","X_init",X);
               assignin("base","Y_init",Y);
            else
                app.outPut_RD('请先设置！')
            end
                if value2 == 0
                    app.UIPlotRD.XLabel.String = '距离谱单元序号';
                    app.UIPlotRD.YLabel.String = '频谱单元序号';
                    surf(app.UIPlotRD,X,Y,abs(Rd_plot),'Edgecolor','none');
                    view(app.UIPlotRD,0,90);
                else
                    xaxis =dis.*(-l:l);
                    yaxis = f.*(-k:k);
                    x = linspace(dis*-l,dis*l,2*l+1);
                    y = linspace(f*-k,f*k,2*k+1);
                    [Y,X] =meshgrid(y,x);
                    assignin("base","X",X);
                    assignin("base","Y",Y);
                    app.UIPlotRD.XLabel.String = '距离/m';
                    app.UIPlotRD.YLabel.String = '频率/HZ';
                    surf(app.UIPlotRD,X,Y,abs(Rd_plot),'Edgecolor','none');
                    view(app.UIPlotRD,0,90);
                    axis(app.UIPlotRD,[min(xaxis) max(xaxis) min(yaxis) max(yaxis)]);
                end
      
        end

        % Button pushed function: Button_PlotRdclear
        function Button_PlotRdclearPushed(app, event)
            cla(app.UIPlotRD);
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            cla(app.UIPlotTracePoints);
        end

        % Button pushed function: Button_PlotMoveStateLocResult
        function Button_PlotMoveStateLocResultPushed(app, event)
            if size(app.MoveTarget_result,2)>0 && app.is_SetMoveTarget==1
                scatter(app.UIPlotMoveStateTrace,app.MoveTarget_result(1,:),app.MoveTarget_result(2,:),'filled',"d","g")
                text(app.UIPlotMoveStateTrace,app.MoveTarget_result(1,1)+5,app.MoveTarget_result(2,1)+5,'T')
            end
        end

        % Value changed function: Button_MoveStateAssigninStruct
        function Button_MoveStateAssigninStructValueChanged(app, event)
            value = app.Button_MoveStateAssigninStruct.Value;
            if size(app.MoveTarget_result,2)>0
                app.LOCUIInstantPosition{1}=app.LOCUIInstantPosition{1}+1;
                app.LOCUIInstantPosition{app.LOCUIInstantPosition{1}+1}=app.MoveTarget_struct;
                app.outPut_MoveTargetLoc(sprintf('CPI%d：定位结果矩阵加入至LOCUInstantPosition',app.LOCUI_CPI_ORDER));
               
            else
                app.outPut_MoveTargetLoc(sprintf('请加入有效的定位结果数据! '));
            end
            app.Button_MoveStateAssigninStruct.Value =0;
        end

        % Value changed function: 
        % Button_MoveStateAssigninStruct_delete
        function Button_MoveStateAssigninStruct_deleteValueChanged(app, event)
            value = app.Button_MoveStateAssigninStruct_delete.Value;
            Assin_num =app.LOCUIInstantPosition{1};
            if Assin_num >0
                app.LOCUIInstantPosition{Assin_num+1}={};
                Assin_num=Assin_num-1;
                app.LOCUIInstantPosition{1}=Assin_num;
                str = sprintf('LOCUIInstantPosition删除最新结构体数据');
                app.outPut_MoveTargetLoc(str);
                str = sprintf('LOCUIInstantPosition剩余 %d 组结构体数据',Assin_num);
                app.outPut_MoveTargetLoc(str);
            else
                app.outPut_MoveTargetLoc('LOCUIInstantPosition剩余0组结构体数据！')
            end
            app.Button_MoveStateAssigninStruct_delete.Value=0;
        end

        % Value changed function: TextArea_MoveStateLoc
        function TextArea_MoveStateLocValueChanged(app, event)
            value = app.TextArea_MoveStateLoc.Value;
           
        end

        % Value changed function: TextArea_CPIGroup
        function TextArea_CPIGroupValueChanged(app, event)
            value = app.TextArea_CPIGroup.Value;
            
        end

        % Value changed function: CheckClearAllStation
        function CheckClearAllStationValueChanged(app, event)
            value = app.CheckClearAllStation.Value;
            if value==1
               app.is_SetMoveTarget=0;
               app.MoverTargetcell_index=0;
               app.MoverTargetcell_cell={};
               app.MoverTargetcell_cell{1}=0;
               app.outPut_MoveTargetLoc("成功清除所有站数据！")
            end
            app.CheckClearAllStation.Value=0;
        end

        % Value changed function: Button_deleteCurrentState
        function Button_deleteCurrentStateValueChanged(app, event)
            value = app.Button_deleteCurrentState.Value;
            index =app.MoverTargetcell_index;
            if index==0
                app.outPut_MoveTargetLoc(sprintf("当前站数%d，无可删除站数据",index));
                app.Button_deleteCurrentState.Value=0;
                return;
            end
            app.MoverTargetcell_cell{index}=[];
            app.MoverTargetcell_index=app.MoverTargetcell_index-1;
            app.outPut_MoveTargetLoc(sprintf("删除第%d站数据，剩余%d站数据！",index,index-1));
            app.Button_deleteCurrentState.Value=0;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab_connectMonitor_UI = uitab(app.TabGroup);
            app.Tab_connectMonitor_UI.Title = '连接监视服务器';
            app.Panel = uipanel(app.Tab_connectMonitor_UI);
            app.Panel.Title = '监视服务器连接';
            app.IPLabel = uilabel(app.Panel);
            app.IPLabel.Text = '监视服务器IP:';
            app.IPdisplay = uieditfield(app.Panel, 'text');
            app.IPdisplay.Value = '192.168.0.50';
            app.Button_connect = uibutton(app.Panel, 'state');
            app.Button_connect.ValueChangedFcn = createCallbackFcn(app, @Button_connectValueChanged, true);
            app.Button_connect.Text = '连接服务器';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '帧数量:';
            app.ZhenNumdisplay = uieditfield(app.Panel, 'numeric');
            app.ZhenNumdisplay.Value = 1;
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '帧接收状态';
            app.Textconnect = uitextarea(app.Panel);
            app.Label_6 = uilabel(app.Panel);
            app.Label_6.Text = '端口号:';
            app.Portdisplay = uieditfield(app.Panel, 'numeric');
            app.Portdisplay.Value = 8088;
            app.clear1 = uibutton(app.Panel, 'push');
            app.clear1.ButtonPushedFcn = createCallbackFcn(app, @clear1Pushed, true);
            app.clear1.Text = '清空';
            app.Panel_2 = uipanel(app.Tab_connectMonitor_UI);
            app.Panel_2.Title = '混合数据帧解析';
            app.Label_3 = uilabel(app.Panel_2);
            app.Label_3.Text = '帧解析信息';
            app.TextArea_2 = uitextarea(app.Panel_2);
            app.Button_decode = uibutton(app.Panel_2, 'state');
            app.Button_decode.ValueChangedFcn = createCallbackFcn(app, @Button_decodeValueChanged, true);
            app.Button_decode.Text = '帧解析';
            app.Button_file_select = uibutton(app.Panel_2, 'state');
            app.Button_file_select.ValueChangedFcn = createCallbackFcn(app, @Button_file_selectValueChanged, true);
            app.Button_file_select.Text = '...';
            app.Mixframe2Label = uilabel(app.Panel_2);
            app.Mixframe2Label.Text = 'Mixframe2:';
            app.Edit_filedirdisplay = uieditfield(app.Panel_2, 'text');
            app.Edit_filedirdisplay.Editable = 'off';
            app.Edit_filedirdisplay.Value = ' ';
            app.E2Label = uilabel(app.Panel_2);
            app.E2Label.Text = '文件路径: E:\混合数据帧2';
            app.clear2 = uibutton(app.Panel_2, 'push');
            app.clear2.ButtonPushedFcn = createCallbackFcn(app, @clear2ButtonPushed, true);
            app.clear2.Text = '清空';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '升空散射体辅助定位';
            app.Panel_3 = uipanel(app.Tab_2);
            app.Panel_3.Title = '定位信息提示';
            app.Label_10 = uilabel(app.Panel_3);
            app.Label_10.Text = '信息打印';
            app.TextArea_Loc = uitextarea(app.Panel_3);
            app.Button_clearLocinfo = uibutton(app.Panel_3, 'push');
            app.Button_clearLocinfo.ButtonPushedFcn = createCallbackFcn(app, @Button_clearLocinfoPushed, true);
            app.Button_clearLocinfo.Text = '清空';
            app.Button_StartLoc = uibutton(app.Panel_3, 'state');
            app.Button_StartLoc.ValueChangedFcn = createCallbackFcn(app, @Button_StartLocValueChanged, true);
            app.Button_StartLoc.Text = '启动定位';
            app.Panel_4 = uipanel(app.Tab_2);
            app.Panel_4.Title = '定位参数设置';
            app.E2Label_3 = uilabel(app.Panel_4);
            app.E2Label_3.Text = '文件路径: E:\混合数据帧2解析';
            app.Mixframe2Label_3 = uilabel(app.Panel_4);
            app.Mixframe2Label_3.Text = 'Mixframe2:';
            app.Edit_filedirLoc = uieditfield(app.Panel_4, 'text');
            app.Edit_filedirLoc.Editable = 'off';
            app.Edit_filedirLoc.Value = ' ';
            app.Label_9 = uilabel(app.Panel_4);
            app.Label_9.Text = '虚警率设置';
            app.DropDown_Pf = uidropdown(app.Panel_4);
            app.DropDown_Pf.Items = {'1e-4', '1e-3', '1e-2', ''};
            app.DropDown_Pf.Value = '1e-4';
            app.Button_LocSet = uibutton(app.Panel_4, 'state');
            app.Button_LocSet.ValueChangedFcn = createCallbackFcn(app, @Button_LocSetValueChanged, true);
            app.Button_LocSet.Text = '设置';
            app.Button_fileselect_Loc = uibutton(app.Panel_4, 'state');
            app.Button_fileselect_Loc.ValueChangedFcn = createCallbackFcn(app, @Button_fileselect_LocValueChanged, true);
            app.Button_fileselect_Loc.Text = '...';
            app.Panel_5 = uipanel(app.Tab_2);
            app.Panel_5.Title = '散射体布局图形化展示';
            app.UIPlotScatter = uiaxes(app.Panel_5);
            title(app.UIPlotScatter, '散射体布局预览')
            xlabel(app.UIPlotScatter, '米/m')
            ylabel(app.UIPlotScatter, '米/m')
            app.Button_5 = uibutton(app.Panel_5, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '清除';
            app.Button_4 = uibutton(app.Tab_2, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '定位结果图形化';
            app.Button_AssigninStruct = uibutton(app.Tab_2, 'state');
            app.Button_AssigninStruct.ValueChangedFcn = createCallbackFcn(app, @Button_AssigninStructValueChanged, true);
            app.Button_AssigninStruct.Text = '加入点迹分选区';
            app.Button_AssigninStruct_delete = uibutton(app.Tab_2, 'state');
            app.Button_AssigninStruct_delete.ValueChangedFcn = createCallbackFcn(app, @Button_AssigninStruct_deleteValueChanged, true);
            app.Button_AssigninStruct_delete.Text = '删除新入结构体';
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '跟踪滤波';
            app.CPIGroupPanel = uipanel(app.Tab_3);
            app.Button_CPIsource_select = uibuttongroup(app.CPIGroupPanel);
            app.Button_CPIsource_select.SelectionChangedFcn = createCallbackFcn(app, @Button_CPIsource_selectSelectionChanged, true);
            app.Button_CPIsource_select.Title = 'CPI时刻数据结构体来源选择';
            app.InstantPositionButton = uitogglebutton(app.Button_CPIsource_select);
            app.InstantPositionButton.Text = 'InstantPosition文件夹提取';
            app.InstantPositionButton.Value = true;
            app.InstantPositionButton_2 = uitogglebutton(app.Button_CPIsource_select);
            app.InstantPositionButton_2.Text = 'InstantPosition定位界面提取';
            app.InstantPositionLabel = uilabel(app.CPIGroupPanel);
            app.InstantPositionLabel.Text = 'InstantPosition:';
            app.Edit_filedirStructData = uieditfield(app.CPIGroupPanel, 'text');
            app.Edit_filedirStructData.Editable = 'off';
            app.Edit_filedirStructData.Value = ' ';
            app.Button_file_select_2 = uibutton(app.CPIGroupPanel, 'state');
            app.Button_file_select_2.ValueChangedFcn = createCallbackFcn(app, @Button_file_select_2ValueChanged, true);
            app.Button_file_select_2.Text = '...';
            app.E2CPILabel = uilabel(app.CPIGroupPanel);
            app.E2CPILabel.Text = '文件路径: E:\混合数据帧2解析\CPI时刻的瞬时定位结果';
            app.Label_12 = uilabel(app.CPIGroupPanel);
            app.Label_12.Text = '>1';
            app.Panel_6 = uipanel(app.Tab_3);
            app.Label_11 = uilabel(app.Panel_6);
            app.Label_11.Text = '信息提示';
            app.TextArea_CPIGroup = uitextarea(app.Panel_6);
            app.TextArea_CPIGroup.ValueChangedFcn = createCallbackFcn(app, @TextArea_CPIGroupValueChanged, true);
            app.Button_plotCPIGroupTrace = uibutton(app.Panel_6, 'push');
            app.Button_plotCPIGroupTrace.ButtonPushedFcn = createCallbackFcn(app, @Button_plotCPIGroupTracePushed, true);
            app.Button_plotCPIGroupTrace.Text = '启动跟踪滤波';
            app.Button_clear3_2 = uibutton(app.Panel_6, 'state');
            app.Button_clear3_2.ValueChangedFcn = createCallbackFcn(app, @Button_clear3_2ValueChanged, true);
            app.Button_clear3_2.Text = '清空';
            app.Button_ReturnServerceResult = uibutton(app.Panel_6, 'state');
            app.Button_ReturnServerceResult.ValueChangedFcn = createCallbackFcn(app, @Button_ReturnServerceResultValueChanged, true);
            app.Button_ReturnServerceResult.Text = '回传服务器';
            app.UIPlotTracePoints = uiaxes(app.Tab_3);
            title(app.UIPlotTracePoints, '点迹分选滤波结果')
            xlabel(app.UIPlotTracePoints, '米/m')
            ylabel(app.UIPlotTracePoints, '米/m')
            app.Panel_set_tracepoint_parameter = uipanel(app.Tab_3);
            app.Panel_set_tracepoint_parameter.Title = '波门参数设置';
            app.VminEditFieldLabel = uilabel(app.Panel_set_tracepoint_parameter);
            app.VminEditFieldLabel.Text = 'Vmin';
            app.VminEditField = uieditfield(app.Panel_set_tracepoint_parameter, 'numeric');
            app.VmaxEditFieldLabel = uilabel(app.Panel_set_tracepoint_parameter);
            app.VmaxEditFieldLabel.Text = 'Vmax';
            app.VmaxEditField = uieditfield(app.Panel_set_tracepoint_parameter, 'numeric');
            app.VmaxEditField.Value = 1;
            app.VzminEditFieldLabel = uilabel(app.Panel_set_tracepoint_parameter);
            app.VzminEditFieldLabel.Text = 'Vzmin';
            app.VzminEditField = uieditfield(app.Panel_set_tracepoint_parameter, 'numeric');
            app.VzmaxEditFieldLabel = uilabel(app.Panel_set_tracepoint_parameter);
            app.VzmaxEditFieldLabel.Text = 'Vzmax';
            app.VzmaxEditField = uieditfield(app.Panel_set_tracepoint_parameter, 'numeric');
            app.VzmaxEditField.Value = 1;
            app.xEditFieldLabel = uilabel(app.Panel_set_tracepoint_parameter);
            app.xEditFieldLabel.Text = 'x坐标波门';
            app.xEditField = uieditfield(app.Panel_set_tracepoint_parameter, 'numeric');
            app.xEditField.Value = 1;
            app.yEditFieldLabel = uilabel(app.Panel_set_tracepoint_parameter);
            app.yEditFieldLabel.Text = 'y坐标波门';
            app.yEditField = uieditfield(app.Panel_set_tracepoint_parameter, 'numeric');
            app.yEditField.Value = 2;
            app.zEditFieldLabel = uilabel(app.Panel_set_tracepoint_parameter);
            app.zEditFieldLabel.Text = 'z坐标波门';
            app.zEditField = uieditfield(app.Panel_set_tracepoint_parameter, 'numeric');
            app.zEditField.Value = 3;
            app.Button_6 = uibutton(app.Tab_3, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '清除';
            app.Tab_4 = uitab(app.TabGroup);
            app.Tab_4.Title = '移动单站定位';
            app.Panel_7 = uipanel(app.Tab_4);
            app.Panel_7.Title = '定位参数设置';
            app.E2CPILabel_2 = uilabel(app.Panel_7);
            app.E2CPILabel_2.Text = '文件路径: E:\混合数据帧2解析\CPI时刻的瞬时定位结果';
            app.Mixframe2Label_4 = uilabel(app.Panel_7);
            app.Mixframe2Label_4.Text = 'Mixframe2:';
            app.Edit_filedirLoc_2 = uieditfield(app.Panel_7, 'text');
            app.Edit_filedirLoc_2.Editable = 'off';
            app.Edit_filedirLoc_2.Value = ' ';
            app.Label_13 = uilabel(app.Panel_7);
            app.Label_13.Text = '站数设置';
            app.DropDownNumSignalState = uidropdown(app.Panel_7);
            app.DropDownNumSignalState.Items = {'1', '2', '3', '4'};
            app.DropDownNumSignalState.Value = '1';
            app.Button_MoveStateLocSet = uibutton(app.Panel_7, 'state');
            app.Button_MoveStateLocSet.ValueChangedFcn = createCallbackFcn(app, @Button_MoveStateLocSetValueChanged, true);
            app.Button_MoveStateLocSet.Text = '设置';
            app.Button_fileselect_Loc_2 = uibutton(app.Panel_7, 'state');
            app.Button_fileselect_Loc_2.ValueChangedFcn = createCallbackFcn(app, @Button_fileselect_Loc_2ValueChanged, true);
            app.Button_fileselect_Loc_2.Text = '...';
            app.Label_15 = uilabel(app.Panel_7);
            app.Label_15.Text = '站内帧数设置';
            app.DropDownNumFrameInState = uidropdown(app.Panel_7);
            app.DropDownNumFrameInState.Items = {'2', '3', '4', '5'};
            app.DropDownNumFrameInState.Value = '2';
            app.Button_deleteCurrentState = uibutton(app.Panel_7, 'state');
            app.Button_deleteCurrentState.ValueChangedFcn = createCallbackFcn(app, @Button_deleteCurrentStateValueChanged, true);
            app.Button_deleteCurrentState.Text = '删除当前站';
            app.CheckClearAllStation = uicheckbox(app.Panel_7);
            app.CheckClearAllStation.ValueChangedFcn = createCallbackFcn(app, @CheckClearAllStationValueChanged, true);
            app.CheckClearAllStation.Text = '清空站数据';
            app.Panel_8 = uipanel(app.Tab_4);
            app.Panel_8.Title = '单站轨迹图形化展示';
            app.UIPlotMoveStateTrace = uiaxes(app.Panel_8);
            title(app.UIPlotMoveStateTrace, '移动轨迹预览')
            xlabel(app.UIPlotMoveStateTrace, '米/m')
            ylabel(app.UIPlotMoveStateTrace, '米/m')
            app.Button_MoveStatePlotclear = uibutton(app.Panel_8, 'push');
            app.Button_MoveStatePlotclear.ButtonPushedFcn = createCallbackFcn(app, @Button_MoveStatePlotclearPushed, true);
            app.Button_MoveStatePlotclear.Text = '清除';
            app.Panel_9 = uipanel(app.Tab_4);
            app.Panel_9.Title = '定位信息提示';
            app.Label_14 = uilabel(app.Panel_9);
            app.Label_14.Text = '信息打印';
            app.TextArea_MoveStateLoc = uitextarea(app.Panel_9);
            app.TextArea_MoveStateLoc.ValueChangedFcn = createCallbackFcn(app, @TextArea_MoveStateLocValueChanged, true);
            app.Button_clearMoveStateLocinfo = uibutton(app.Panel_9, 'push');
            app.Button_clearMoveStateLocinfo.ButtonPushedFcn = createCallbackFcn(app, @Button_clearMoveStateLocinfoPushed, true);
            app.Button_clearMoveStateLocinfo.Text = '清空';
            app.Button_StartMoveStateLoc = uibutton(app.Panel_9, 'state');
            app.Button_StartMoveStateLoc.ValueChangedFcn = createCallbackFcn(app, @Button_StartMoveStateLocValueChanged, true);
            app.Button_StartMoveStateLoc.Text = '启动定位';
            app.Panel_SetSubstractClassParameter = uipanel(app.Tab_4);
            app.Panel_SetSubstractClassParameter.Title = '聚类参数设置';
            app.yaEditFieldLabel = uilabel(app.Panel_SetSubstractClassParameter);
            app.yaEditFieldLabel.Text = '邻域半径ya';
            app.yaEditField = uieditfield(app.Panel_SetSubstractClassParameter, 'numeric');
            app.yaEditField.Value = 5.5;
            app.ybEditFieldLabel = uilabel(app.Panel_SetSubstractClassParameter);
            app.ybEditFieldLabel.Text = '修正半径yb';
            app.ybEditField = uieditfield(app.Panel_SetSubstractClassParameter, 'numeric');
            app.ybEditField.Value = 8.5;
            app.K_upEditFieldLabel = uilabel(app.Panel_SetSubstractClassParameter);
            app.K_upEditFieldLabel.Text = 'K_up';
            app.K_upEditField = uieditfield(app.Panel_SetSubstractClassParameter, 'numeric');
            app.K_upEditField.Value = 0.55;
            app.K_downEditFieldLabel = uilabel(app.Panel_SetSubstractClassParameter);
            app.K_downEditFieldLabel.Text = 'K_down';
            app.K_downEditField = uieditfield(app.Panel_SetSubstractClassParameter, 'numeric');
            app.K_downEditField.Value = 0.3;
            app.rEditFieldLabel = uilabel(app.Panel_SetSubstractClassParameter);
            app.rEditFieldLabel.Text = '同簇半径r';
            app.rEditField = uieditfield(app.Panel_SetSubstractClassParameter, 'numeric');
            app.rEditField.Value = 6.5;
            app.yb15yaLabel = uilabel(app.Panel_SetSubstractClassParameter);
            app.yb15yaLabel.Text = 'yb=1.5ya';
            app.mLabel = uilabel(app.Panel_SetSubstractClassParameter);
            app.mLabel.Text = 'm';
            app.mLabel_2 = uilabel(app.Panel_SetSubstractClassParameter);
            app.mLabel_2.Text = 'm';
            app.mLabel_3 = uilabel(app.Panel_SetSubstractClassParameter);
            app.mLabel_3.Text = 'm';
            app.Button_PlotMoveStateLocResult = uibutton(app.Tab_4, 'push');
            app.Button_PlotMoveStateLocResult.ButtonPushedFcn = createCallbackFcn(app, @Button_PlotMoveStateLocResultPushed, true);
            app.Button_PlotMoveStateLocResult.Text = '定位结果图形化';
            app.Button_MoveStateAssigninStruct = uibutton(app.Tab_4, 'state');
            app.Button_MoveStateAssigninStruct.ValueChangedFcn = createCallbackFcn(app, @Button_MoveStateAssigninStructValueChanged, true);
            app.Button_MoveStateAssigninStruct.Text = '加入点迹分选区';
            app.Button_MoveStateAssigninStruct_delete = uibutton(app.Tab_4, 'state');
            app.Button_MoveStateAssigninStruct_delete.ValueChangedFcn = createCallbackFcn(app, @Button_MoveStateAssigninStruct_deleteValueChanged, true);
            app.Button_MoveStateAssigninStruct_delete.Text = '删除新入结构体';
            app.Tab_RDplot_UI = uitab(app.TabGroup);
            app.Tab_RDplot_UI.Title = '距离多普勒谱';
            app.UIPlotRD = uiaxes(app.Tab_RDplot_UI);
            title(app.UIPlotRD, '距离多普勒谱')
            xlabel(app.UIPlotRD, '距离单元')
            ylabel(app.UIPlotRD, '频谱单元')
            app.Panel_RD = uipanel(app.Tab_RDplot_UI);
            app.Panel_RD.Title = '选择混合数据帧2';
            app.E2Label_2 = uilabel(app.Panel_RD);
            app.E2Label_2.Text = '文件路径: E:\混合数据帧2解析';
            app.Mixframe2Label_2 = uilabel(app.Panel_RD);
            app.Mixframe2Label_2.Text = 'Mixframe2:';
            app.Edit_filedirdeocde = uieditfield(app.Panel_RD, 'text');
            app.Edit_filedirdeocde.Editable = 'off';
            app.Edit_filedirdeocde.Value = ' ';
            app.Button_fileselect_decode = uibutton(app.Panel_RD, 'state');
            app.Button_fileselect_decode.ValueChangedFcn = createCallbackFcn(app, @Button_fileselect_decodeValueChanged, true);
            app.Button_fileselect_decode.Text = '...';
            app.Label_7 = uilabel(app.Panel_RD);
            app.Label_7.Text = '扇区选择';
            app.DropRDorder = uidropdown(app.Panel_RD);
            app.DropRDorder.Items = {'1        (0,30)', '2        (30,60)', '3        (60,90)', '4        (90,120)', '5        (120,150)', '6        (150,180)', '7        (180,210)', '8        (210,240)', '9        (240,270)', '10      (270,300)', '11      (300,330)', '12      (330,360)'};
            app.DropRDorder.Value = '1        (0,30)';
            app.Button_set = uibutton(app.Panel_RD, 'state');
            app.Button_set.ValueChangedFcn = createCallbackFcn(app, @Button_setValueChanged, true);
            app.Button_set.Text = '设置';
            app.Button_plotRD = uibutton(app.Tab_RDplot_UI, 'push');
            app.Button_plotRD.ButtonPushedFcn = createCallbackFcn(app, @Button_plotRDPushed, true);
            app.Button_plotRD.Text = '距离多谱勒图绘制';
            app.Label_8 = uilabel(app.Tab_RDplot_UI);
            app.Label_8.Text = '绘制信息';
            app.TextArea_RDinfo = uitextarea(app.Tab_RDplot_UI);
            app.TextArea_RDinfo.ValueChangedFcn = createCallbackFcn(app, @TextArea_RDinfoValueChanged, true);
            app.Button_clear3 = uibutton(app.Tab_RDplot_UI, 'state');
            app.Button_clear3.ValueChangedFcn = createCallbackFcn(app, @Button_clear3ValueChanged, true);
            app.Button_clear3.Text = '清空';
            app.CheckBox = uicheckbox(app.Tab_RDplot_UI);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '切换单位';
            app.Button_PlotRdclear = uibutton(app.Tab_RDplot_UI, 'push');
            app.Button_PlotRdclear.ButtonPushedFcn = createCallbackFcn(app, @Button_PlotRdclearPushed, true);
            app.Button_PlotRdclear.Text = '清除';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ComSer2_tcpapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end