classdef ComSer2Formal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Panel                 matlab.ui.container.Panel
        IPLabel               matlab.ui.control.Label
        IPdisplay             matlab.ui.control.EditField
        Label                 matlab.ui.control.Label
        Portdisplay           matlab.ui.control.NumericEditField
        Panel_2               matlab.ui.container.Panel
        Label_8               matlab.ui.control.Label
        ScatterCheckBox       matlab.ui.control.CheckBox
        DOACheckBox           matlab.ui.control.CheckBox
        Label_9               matlab.ui.control.Label
        Label_3               matlab.ui.control.Label
        DropDown_Pf           matlab.ui.control.DropDown
        Label_10              matlab.ui.control.Label
        Label_11              matlab.ui.control.Label
        Label_4               matlab.ui.control.Label
        DropNumSignalState    matlab.ui.control.DropDown
        Label_5               matlab.ui.control.Label
        DropNumFrameInState   matlab.ui.control.DropDown
        Label_12              matlab.ui.control.Label
        Label_7               matlab.ui.control.Label
        DropFramenumForTrace  matlab.ui.control.DropDown
        Button_set            matlab.ui.control.StateButton
        Button                matlab.ui.control.Button
        Button_connect        matlab.ui.control.StateButton
        Label_13              matlab.ui.control.Label
        Text_mainMessage      matlab.ui.control.TextArea
        Button_clearmessage   matlab.ui.control.StateButton
        Button_test           matlab.ui.control.Button
        connectBreak          matlab.ui.control.StateButton
    end

    
    properties (Access = public)
        FramePool   
        FrameNumInPool
        FrameByteData 
        isavlidecoder 
        Pointtrace_cell 
        Pointtrace_num 
        isSetLocModel %  先设置定位方式 再进行参数设置
        isSetButton   %  连接服务器之前的确认信息按钮
        mainMessage
        MainIp
        MainPort
        MainPf
        MainState_num           % 单站纯DOA定位站数
        MainFrameInState_num    % 单站纯DOA定位站内帧数
        MainFrameTrace_num
        CPI_num                 % 作为点迹分选的输入参数
        param_bomen             % 
        isSetBomen   % 波门参数是否设置标志位
        CONNECTBREAK
    end
    
    methods (Access = public)
        
        function [tcp,connectframe] = initalConnectChannel(~,Ip,Port)
            %定义连接帧
            connectframe = zeros(1,13);                               % 与监视服务器连接的访问帧
            connectframe(1) = 221;                                    % DD
            connectframe(2) = 204;                                    % CC
            connectframe(3) = 187;                                    % BB
            connectframe(4) = 170;                                    % AA
            connectframe(5) = 5;                                      % 第5:8位 连接帧长度
            connectframe(9) = 51;                                     % 0x33
            connectframe(10) = 16;                                    % 0x10   入网ID号
            connectframe(12) = 2;                                     % 0x02
            %初始化TCP对象
            tcp=tcpip(Ip,Port,'NetworkRole','client','Timeout',10);
            tcp.InputBuffersize=5e6;                                 % 接收通道最多容纳50帧 内存空间5M 一帧长度0.1M
            tcp.OutputBuffersize=2e6;                                % 必须在打开之前设置buffer长度 发送通道最多容纳20帧 内存空间2M
            tcp.ByteOrder='littleEndian' ;
        end
        
        function creatTimer(app,tcp,tim,Timelong)
            Break = 0;
            tim.UserData = Break;
            tim.StartFcn = @TimerStart;
            tim.TimerFcn = {@TimerPerforme,app,tcp};
            tim.StopFcn  = @TimerCleanup;
            tim.Period = 1;                   % 回调函数执行间隔
            tim.StartDelay = 0.5;             % 大于或等于 0 的数字，用于以秒为单位指定启动计时器和首次执行 TimerFcn 中指定的函数之间的延迟
            tim.TasksToExecute = Timelong;    % 指定定时器执行TimeFcn的执行次数
            tim.ExecutionMode = 'fixedSpacing';
            function TimerPerforme(~,event,app,tcp)
                disp([event.Type,' Function starts to work at ',datestr(event.Data.time,'dd-mmm-yyyy HH:MM:SS.FFF')]);
                sigframe_length = 167068;               % 帧头:8位+混合数据帧长度:167060
                if tcp.BytesAvailable >= sigframe_length
                    app.availableDate(tcp,sigframe_length);
                end
                if app.isavlidecoder == 1
                    app.FrameNumInPool = app.FrameNumInPool+1;
                    Poolindex = app.FrameNumInPool;
                    ByteDate = app.FrameByteData;
                    [app.FramePool{Poolindex},~] = app.decoder(ByteDate);
                    app.isavlidecoder = 0;
                end
            end
            
            function TimerStart(~,event)
                disp([event.Type,' Timer starts to work at ',datestr(event.Data.time,'dd-mmm-yyyy HH:MM:SS.FFF')]);
            end
            
            function TimerCleanup(tim,event)
                disp([event.Type,' Timer ends to work at',datestr(event.Data.time,'dd-mmm-yyyy HH:MM:SS.FFF')])
                set(tim,'UserData',-1);
            end
        end
        function creatTimer2(app,tim,Timelong,tcp)
            Break = 0;
            tim.UserData = Break;
            tim.StartFcn = @TimerStart;
            tim.TimerFcn ={@TimerPerforme,app,tcp};
            tim.StopFcn  = @TimerCleanup;
            tim.Period = 1;                   % 回调函数执行间隔
            tim.StartDelay = 0;               % 大于或等于 0 的数字，用于以秒为单位指定启动计时器和首次执行 TimerFcn 中指定的函数之间的延迟
            tim.TasksToExecute = Timelong;    % 指定定时器执行TimeFcn的执行次数
            tim.ExecutionMode = 'fixedSpacing';
            function TimerPerforme(~,event,app,tcp)
                disp([event.Type,' Function starts to work at ',datestr(event.Data.time,'dd-mmm-yyyy HH:MM:SS.FFF')]);
                drawnow;
                isSendHeart = app.sendHeart(tcp);
                if isSendHeart ==0
                    app.CONNECTBREAK = 1;
                end
            end
            
            function TimerStart(~,event)
                disp([event.Type,' Timer starts to work at ',datestr(event.Data.time,'dd-mmm-yyyy HH:MM:SS.FFF')]);
            end
            
            function TimerCleanup(~,event)
                disp([event.Type,' Timer ends to work at',datestr(event.Data.time,'dd-mmm-yyyy HH:MM:SS.FFF')])
            end
        end
        
        function availableDate(app,tcp,sigframe_length)
            avail_length=sigframe_length;   % 帧的长度(携带帧头)
            content_start = 85;  content_end=170;
            data=uint8(fread(tcp,avail_length,'uint8'));
                        
            frame_part1 = sum(data(1:4));   % 帧头：DDCCBBAA
            if frame_part1 == 782
                frame_part2 =167060;        % data(5)+data(6)*2^8+data(7)*2^16+data(8)*2^32;
                if frame_part2 == 167060
                    if data(10) ==16 && data(9) == content_start && data(9+167059) == content_end
                        app.FrameByteData = data(1:avail_length);
                        app.isavlidecoder = 1;
                    end
                end
            end 
        end
        
        function  MutilTargetLocResult = constructTraceResult(app,LastCPI_Struct,XYZ,V_xyz)
            MutilTargetLocResult = cell(1,16);
            for i=1:10
                if i == 6
                    MutilTargetLocResult{6}=LastCPI_Struct{i}(1:2,1:6);  % UCA经纬度速度and磁场
                    MutilTargetLocResult{6}(1,1:6)=[114.432868,30.516793,65,0,0,0];   % 正式发布删掉
                    MutilTargetLocResult{7}=0;                           % 帧检测信息
                elseif i==7
                    MutilTargetLocResult{8} = LastCPI_Struct{7}(1,1);    % 升空散射体数量
                    MutilTargetLocResult{9} = zeros(4,8);
                    MutilTargetLocResult{9}(1:end,:) = LastCPI_Struct{7}(2:end,:);
                elseif i==8
                    DOA_num = LastCPI_Struct{8}(1,1);
                    MutilTargetLocResult{10} = DOA_num;    % DOA数量
                    MutilTargetLocResult{11} = zeros(7,3);
                    MutilTargetLocResult{11}(1:end,:) = LastCPI_Struct{8}(2:end,:);    % DOA
                elseif i==9
                    T_num =  LastCPI_Struct{9}(1,1);
                    MutilTargetLocResult{12} = T_num;    % 可测向T数量
                    MutilTargetLocResult{13} = zeros(4,5);
                    MutilTargetLocResult{13}(1:end,:) = LastCPI_Struct{9}(2:end,:);    % 测向
                elseif i==10
                    MutilTargetLocResult{14}=LastCPI_Struct{i};
                else
                    MutilTargetLocResult{i}=LastCPI_Struct{i};
                end
            end
            MutilTargetLocResult{15}=size(XYZ,2);
            MutilTargetLocResult{16}=zeros(size(XYZ,2),6);
            MutilTargetLocResult{16}(:,1:3) = XYZ';
            MutilTargetLocResult{16}(:,4:6) =V_xyz';
        end
        
        function returnTraceResult(app,tcp,Traceresult)
            headframe = zeros(1,10);                               % 与监视服务器连接的访问帧
            headframe(1) = 221;                                    % DD
            headframe(2) = 204;                                    % CC
            headframe(3) = 187;                                    % BB
            headframe(4) = 170;                                    % AA
            headframe(5) = 14;                                     % 524
            headframe(6) = 2;                                      %
            headframe(9) = 85;                                     % 0x55
            headframe(10) = 102;                                   % 0x66
            Length=0;
            for i=1:16
                row = size(Traceresult{i},1);
                cloumn =size(Traceresult{i},2);
                Length = Length+row* cloumn;
            end
            Length = Length*4+4;  % 111是除去0x55、0x66、0xAA 0xAA外纯32bit数据
            length = typecast(int32(Length),'uint8');        % 计算帧长度
%           app.outPut_CPIGroupTrace(sprintf('待发送%d字节数据',Length))
            headframe(5:8)=length;
            fwrite(tcp,uint8(headframe),'uint8');
            for i=1:16
                data = Traceresult{i};
                for j=1:size(data,1)
                    for k=1:size(data,2)
                        fwrite(tcp,single(data(j,k)),'single');
                    end
                end
            end
            fwrite(tcp,uint8(170),"uint8");
            fwrite(tcp,uint8(170),"uint8");
            str = sprintf("  第 %.1f CPI时刻: 回传至服务器！",(app.CPI_num-str2double(app.DropFramenumForTrace.Value)+1)); 
            app.outPut_Message(str);
        end
        
        function isSendHeart = sendHeart(~,tcp)
            heartframe = zeros(1,13);                               % 与监视服务器连接的访问帧
            heartframe(1) = 221;                                    % DD
            heartframe(2) = 204;                                    % CC
            heartframe(3) = 187;                                    % BB
            heartframe(4) = 170;                                    % AA
            heartframe(5) = 5;                                      % 第5:8位 帧长度 帧
            heartframe(9) = 85;                                     % 0x55   帧头
            heartframe(10) = 34;                                    % 0x22   
            heartframe(13) = 170;                                   % 0xaa   帧尾
           try
               fwrite(tcp,heartframe,'uint8');
               isSendHeart = 1;
           catch ME
               disp(ME)
               isSendHeart = 0;
           end
        end
        
        function acquireMixframeInConnectChannel(app,tcp)
            sigframe_length = 167068;               % 帧头:8位+混合数据帧长度:167060
            if tcp.BytesAvailable >= sigframe_length
                app.availableDate(tcp,sigframe_length);
            end
            if app.isavlidecoder == 1
                app.FrameNumInPool = app.FrameNumInPool+1;
                Poolindex = app.FrameNumInPool;
                ByteDate = app.FrameByteData;
                [app.FramePool{Poolindex},~] = app.decoder(ByteDate);
                app.isavlidecoder = 0;
            end
        end
        
        function  initQinParameter(app)
            app.FramePool = cell(1,100);
            app.FrameNumInPool = 0;
            app.Pointtrace_num = 0;
            app.CPI_num=0;
            Targets_nums=0;   % 记录当前点迹初始点数量
            All_targets =cell(1,1);
            save('E:\混合数据帧2解析\点迹分选中间结果存储\All_targets.mat','All_targets');
            save('E:\混合数据帧2解析\点迹分选中间结果存储\Targets_nums.mat','Targets_nums')
        end
        
        function  initalQin1(app)
            Process_M = 0;
            ClusterDoa_cell = cell(1,1);
            StateLoc_recoder= zeros(3,1);
            targetLocResult = zeros(4,1);
            save('E:\混合数据帧2解析\情景1中间结果存储\Process_M.mat','Process_M');
            save('E:\混合数据帧2解析\情景1中间结果存储\ClusterDoa_cell.mat','ClusterDoa_cell');
            save('E:\混合数据帧2解析\情景1中间结果存储\StateLoc_recoder.mat','StateLoc_recoder');
            save('E:\混合数据帧2解析\情景1中间结果存储\targetLocResult.mat','targetLocResult');
        end
        
        function isavailable = isAvailableStation(~,Process_M,FramePool)
              if Process_M == 0
                  LBH_ref = [FramePool{1}{12},FramePool{1}{13},FramePool{1}{14}]';
                  save('E:\混合数据帧2解析\情景1中间结果存储\LBH_ref.mat','LBH_ref')
                  isavailable = 1;
              end
              if Process_M >= 1
                 StateLoc_recoder = load('E:\混合数据帧2解析\情景1中间结果存储\StateLoc_recoder.mat').StateLoc_recoder;
                 LBH_ref  = load('E:\混合数据帧2解析\情景1中间结果存储\LBH_ref.mat').LBH_ref;
                 LBH_next = [FramePool{1}{12},FramePool{1}{13},FramePool{1}{14}]';
%                xyz_next = LBM_XYZ(LBH_next,LBH_ref);    % 正式外场时解锁
                 xyz_next =  LBH_next;  
                 pre=xyz_next-StateLoc_recoder(:,end);
                 dis=sqrt(pre'*pre);     
                 if dis>=20
                     isavailable=1;
                 else
                     isavailable=0;
                 end
                 
              end
              
             function station_xyz=LBM_XYZ(coordinate_LBH,cen_state)
                % coordinate_LBH各个站位经纬度坐标，数据类型：矩阵
                % cen_state 参考站心 L0_B0_H0 1*3维

                shape=size(coordinate_LBH); 
                L0=cen_state(1); B0=cen_state(2); H0=cen_state(3);
                R=6372566;  %地球半径
                station_xyz=zeros(shape(1),shape(2));
                for col=1:shape(2)
                    LBH=coordinate_LBH(:,col);
                    station_xyz(1,col)=(R+LBH(3))*cos(LBH(2))*sin(LBH(1)-L0);
                    station_xyz(2,col)=(R+LBH(3))*sin(LBH(2))*cos(B0)-(R+LBH(3))*cos(LBH(2))*sin(B0)*cos(LBH(1)-L0);
                    station_xyz(3,col)=(R+LBH(3))*cos(LBH(2))*cos(B0)*cos(LBH(1)-L0)+(R+LBH(3))*sin(LBH(2))*sin(B0)-R-H0;
                end
            end
        end
        
        function outPut_Message(app,str)
            string = sprintf('%s\n', str);
            app.mainMessage=horzcat(app.mainMessage,string); % 水平串联数组
            app.Text_mainMessage.Value = app.mainMessage;
        end
        
        function [Struct_data,isdecode]=decoder(~,ByteDate)
            ByteDate = uint8(ByteDate(9:end)');
            [~,len]=size(ByteDate) ;
            isdecode = 0;
            Struct_data = 0;
            if len==167060
                %%%%% 全部解帧耗时0.175s = 175 ms
                Struct_data=cell(1,81);            %% 数据存放器
                Struct_data{1,1} = ByteDate(1,1);  %% 帧头
                Struct_data{1,2} = ByteDate(1,2);  %% 功能码
                %%%中心频率解析%%%
                zhong_pinInteger=double(typecast(ByteDate(1,3:4),'uint16'));              %% 中心频率整数部分
                zhong_pinXiaoshu=double(typecast(ByteDate(1,5:6),'uint16'))*2^-16;        %% 中心频率小数部分
                Struct_data{1,3}=zhong_pinInteger+zhong_pinXiaoshu;                       %% 中心频率
                %%%带宽编号%%%
                Order_num = typecast(ByteDate(1,7),'uint8');                  %%带宽编号
                Struct_data{1,4} = Order_num;
                %%%%时间解析%%%%
                Struct_data{1,5}=typecast(ByteDate(1,8:9),'uint16');   %% 年
                Struct_data{1,6}=typecast(ByteDate(1,10),'uint8');     %% 月
                Struct_data{1,7}=typecast(ByteDate(1,11),'uint8');     %% 日
                Struct_data{1,8}=typecast(ByteDate(1,12),'uint8');     %% 时
                Struct_data{1,9}=typecast(ByteDate(1,13),'uint8');     %% 分
                Struct_data{1,10}=typecast(ByteDate(1,14),'uint8');    %% 秒
                Struct_data{1,11}=typecast(ByteDate(1,15:18),'uint32');%% 高精度时标
                %%%%UCA站址信息解析%%%%
                jindu_zheng=typecast(ByteDate(1,20),'int8');                          %% 读取经度整数
                jindu_xiaoshu=typecast(ByteDate(1,21:22),'uint16');                   %% 读取经度小数
                widu_zheng=typecast(ByteDate(1,23),'int8');                           %% 读取纬度整数
                widu_xiaoshu=typecast(ByteDate(1,24:25),'uint16');                    %% 读取纬度小数
                Gaodu =typecast(ByteDate(1,26:27),'int16');                           %% 读取高度整数
                jindu=double(jindu_zheng)+(double(jindu_xiaoshu)/2^16);
                widu=double(widu_zheng)+(double(widu_xiaoshu)/2^16);
                Struct_data{1,12}= jindu;                                              %% 经度
                Struct_data{1,13}= widu;                                               %% 纬度
                Struct_data{1,14}= Gaodu;                                              %% 高度
                jiao_fangxiang = typecast(ByteDate(1,28:29),'uint16');                 %% 航向角整数部分
                jiao_fangXiao = typecast(ByteDate(1,30),'uint8');                      %% 航向角小数部分 0-9
                Struct_data{1,15} = double(jiao_fangxiang)+(jiao_fangXiao)/2^4;        %% 航向角 精确到后0.1°  0-360
                jiao_fuyang = typecast(ByteDate(1,31),'int8');                         %% 俯仰角整数部分
                jiao_fuyangX = typecast(ByteDate(1,32),'uint8');                       %% 俯仰角小数部分 0-9
                Struct_data{1,16} = double(jiao_fuyang)+double(jiao_fuyangX)/2^4;      %% 俯仰角 精确到后0.1°-90-90
                %%%%UCA磁场信息解析%%%%
                magnetic_x = typecast(ByteDate(1,33:34),'int16');
                magnetic_xXiao = typecast(ByteDate(1,35),'uint8');
                if magnetic_x < 0
                    magnetic_xXiao=magnetic_xXiao*-1;
                end
                magnetic_X = double(magnetic_x)+double(magnetic_xXiao)/2^4;
                magnetic_y = typecast(ByteDate(1,36:37),'int16');
                magnetic_yXiao = typecast(ByteDate(1,38),'uint8');
                if magnetic_y < 0
                    magnetic_yXiao=magnetic_yXiao*-1;
                end
                magnetic_Y = double(magnetic_y)+double(magnetic_yXiao)/2^4;
                magnetic_z = typecast(ByteDate(1,39:40),'int16');
                magnetic_zXiao = typecast(ByteDate(1,41),'uint8');
                if magnetic_z < 0
                    magnetic_zXiao=magnetic_zXiao*-1;
                end
                magnetic_Z = double(magnetic_z)+double(magnetic_zXiao)/2^4;
                Struct_data{1,17} = magnetic_X;                                       %% 磁场分量x
                Struct_data{1,18} = magnetic_Y;                                       %% 磁场分量y
                Struct_data{1,19} = magnetic_Z;                                       %% 磁场分量z
                %%%升空散射体Q信息解析%%%
                NumQ = typecast(ByteDate(1,42),'uint8');
                Struct_data{1,20} = NumQ;                                                %% 散射体个数
                index = 1;
                for i=43:20:103
                    Struct_data{1,20+index} = typecast(ByteDate(1,i:i+3),'single');      %% 读取经度整数
                    Struct_data{1,20+index+1} = typecast(ByteDate(1,i+4:i+7),'single');  %% 读取纬度
                    Struct_data{1,20+index+2} = typecast(ByteDate(1,i+8:i+11),'single'); %% 读取高度
                    Struct_data{1,20+index+3} = typecast(ByteDate(1,i+12:i+15),'single');%% 读取速度
                    Struct_data{1,20+index+4} = typecast(ByteDate(1,i+16:i+19),'single');%% 读取方向
                    index = index+5;
                end
                %%%DOA信息解析%%%%
                DoA_num=typecast(ByteDate(1,123),'uint8');
                Struct_data{1,41} = DoA_num;                                                       %% DOA数据个数
                index =1;
                for i=124:4:148
                    Struct_data{1,41+index} = double(typecast(ByteDate(1,i:i+1),'uint16'))/2^3;     %% 方向角0-360
                    Struct_data{1,41+index+1}=double(typecast(ByteDate(1,i+2:i+3),'uint16'))/2^3;   %% 俯仰角0-90
                    index = index + 2;
                end
                %%%%升空散射体T信息解析%%%%%
                Num_T=typecast(ByteDate(1,152),'uint8');
                Struct_data{1,56} = Num_T;                                            %% 可侧向散射体个数
                
                index =1;
                for i=1:4
                    order=typecast(ByteDate(1,152+i),'uint8');                    %% 57-64
                    s_order=floor(double(order)/2^4);
                    doa_order=floor((double(order)/2^4-s_order)*2^4);
                    Struct_data{1,56+index} = s_order;                                %% 可测向升空散射体序号
                    Struct_data{1,56+index+1} = doa_order;                            %% DOA值序号
                    index = index+2;
                end
                %%%参考通道DOA解析%%%%%
                order=typecast(ByteDate(1,157),'uint8');
                Struct_data{1,65} = order;
                %%%协方差矩阵%%%
                Rss = zeros(8,8);
                index = 158;
                for i=1:8
                    for j=1:8
                        rel = typecast(ByteDate(1,index:index+3),'single');
                        img = typecast(ByteDate(1,index+4:index+7),'single');
                        Rss(i,j) = rel+1i*img;
                        index = index+8;
                    end
                end
                Struct_data{1,66} =Rss;
                %%%IQ信号%%%
                IQ = zeros(1,32768);
                index = 670;
                for i=1:32678
                    rel=double(typecast(ByteDate(1,index:index+1),'int16'));
                    img=double(typecast(ByteDate(1,index+2:index+3),'int16'));
                    IQ(1,i)=rel + 1i*img;
                    index = index+4;
                end
                Struct_data{1,67} = IQ;
                %%%十二扇区距离多普勒谱%%%
                Order_num =Struct_data{1,4};
                if Order_num==0
                    scampling=0.203125;  %%信号采样率2013.125ksps
                elseif Order_num==1
                    % CPI_SigFrequent(1,2)=3;       %%带宽3MHz
                    scampling=4.0625;  %%信号采样率4.0625Msps
                elseif Order_num==2
                    % CPI_SigFrequent(1,2)=5;       %%带宽5MHz
                    scampling=8.125;   %%信号采样率8.125Msps
                elseif Order_num==3
                    % CPI_SigFrequent(1,2)=10;       %%带宽10MHz
                    scampling=16.25;   %%信号采样率16.25Msps
                elseif Order_num==4
                    % CPI_SigFrequent(1,2)=30;       %%带宽30MHz
                    scampling=32.5;   %%信号采样率32.25Msps
                end
                if scampling ==32.5
                    l = 163; k=1;
                elseif scampling ==16.25
                    l = 82; k=2;
                elseif scampling ==8.125
                    l = 41; k=3;
                elseif scampling ==4.0625
                    l = 21; k=6;
                elseif scampling ==0.203125
                    l = 1;  k=113;
                end
                index = 131742;
                for i=68:79
                    temple=zeros(2*l+1,2*k+1);
                    for j=1:2*l+1
                        for jj=1:2*k+1                                   % 十二扇区矩阵
                          value1 = typecast(ByteDate(1,index:index+1),'uint16');
                          value3 = typecast(ByteDate(1,index+2),'uint8');
                          temple(j,jj) = (double(value1)*2^-8+double(value3)*2^-16);
%                         temple(j,jj) = (double(value1)*2^-16+double(value3)*2^-24);  正式运行解锁
                          index = index +3;
                        end
                    end
                    Struct_data{1,i} = temple;
                end
                %%%校验码%%%%
                Struct_data{1,80} = typecast(ByteDate(1,167058:167059),'uint16');
                %%%帧尾%%%
                Struct_data{1,81} = typecast(ByteDate(1,167060),'uint8');
                %%Struct_data 数据类型全部转为double类型
                for i=1:81
                    Struct_data{i} = double(Struct_data{i});
                end
                filename = sprintf('%d_%d_%d_%d_%d_%d_%d',Struct_data{1,5},Struct_data{1,6},Struct_data{1,7},Struct_data{1,8},Struct_data{1,9},Struct_data{1,10},Struct_data{1,11});
                name=sprintf('E:\\混合数据帧2解析\\Mixframe2_%s.mat',filename);
                save(name,'Struct_data');
                isdecode = 1;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = '计算服务器二';
            app.FramePool = cell(1,100);
            app.FrameNumInPool = 0;
            app.isavlidecoder = 0;
            app.Pointtrace_num = 0;
            app.CPI_num = 0;
            app.isSetLocModel =0;
            app.param_bomen = zeros(1,7);
            app.isSetBomen = 0;
            app.isSetButton = 0;
            app.CONNECTBREAK = 0;
            app.mainMessage = sprintf('%s\n', '***定位过程信息***');
            app.initalQin1();          % 对情景1中间存储数据进行初始化
        end

        % Value changed function: Button_connect
        function Button_connectValueChanged(app, event)
            % value = app.Button_connect.Value;
         if app.isSetButton == 0
            app.Button_connect.Value = 0;
            app.outPut_Message("请先设置定位方式和参数！");
            return ;
         end
         % 参数
            connect = 1;                                                   % 连接成功标志位 -1：失败 1：成功
            Timelong = inf;
            isStartLoc = 0;
            parameterMoveState_cell = cell(1,2);                           %  站数、站内帧数
            parameterScatter_cell = cell(1,7);                             %  虚警率、散射体是否移动
            app.Pointtrace_cell = cell(1,app.MainFrameTrace_num);     
            if app.ScatterCheckBox.Value ==1
                parameterScatter_cell{1} = app.MainPf;
                parameterScatter_cell{2} = 0;                              %  0 设置flag_yundong=0
            elseif app.DOACheckBox.Value == 1
                parameterMoveState_cell{1} = app.MainState_num;
                parameterMoveState_cell{2} = app.MainFrameInState_num;
                ya = 5.5;
                yb = 8.5;
                r=6.5;
                k_up =0.55;
                k_down = 0.3;
                parameterMoveState_cell{3} = ya;
                parameterMoveState_cell{4} = yb;
                parameterMoveState_cell{5} = r;
                parameterMoveState_cell{6} = k_up;
                parameterMoveState_cell{7} = k_down;
            end
         % 连接监视服务器
            [tcp,connectframe] = app.initalConnectChannel(app.MainIp,app.MainPort);
            assignin("base","tcp",tcp)
            try
                fopen(tcp);
                pause(0.00005);                                       % 停顿50us
                fwrite(tcp,connectframe,'uint8');
            catch
                connect =-1;
            end
            
         % 连接成功运行计算服务器
          if (connect == 1)
            app.isSetButton = 0;  
            isMovetargetModel =app.DOACheckBox.Value;
            tim = timer;
            app.outPut_Message("成功连接服务器！");
%           app.creatTimer(tcp,tim,Timelong)                       % 启动定时器 每隔1s查询、帧有效判断、帧解析
            app.creatTimer2(tim,Timelong,tcp)  
            assignin("base","tim",tim);
            start(tim);
            while(app.CONNECTBREAK == 0)
                app.acquireMixframeInConnectChannel(tcp);
                Mix2frame_num = app.FrameNumInPool;
                if (Mix2frame_num>0 && isMovetargetModel~=1)        % 从FramePool中获取解析帧
                   Mix2frameforloc = app.FramePool{1};
                   app.FramePool(1) = [];                           % 读取完直接从frame的池子中删除
                   app.FrameNumInPool = app.FrameNumInPool-1;
                   isStartLoc =1;
                elseif isMovetargetModel ==1 && Mix2frame_num>=app.MainFrameInState_num
                    Process_M = load('E:\混合数据帧2解析\情景1中间结果存储\Process_M.mat').Process_M;
                    isAvailable = app.isAvailableStation(Process_M,app.FramePool);
                    if isAvailable==0 ,isStartLoc =0; end
                    if isAvailable==1
                        isStartLoc =1;
                        Mix2frameforloc = cell(1,app.MainFrameInState_num);
                        for i=app.MainFrameInState_num:-1:1
                            Mix2frameforloc{i} = app.FramePool{i};
                            app.FramePool(i) = [];                             % 读取完直接从frame的池子中删除
                            app.FrameNumInPool = app.FrameNumInPool-1;
                        end
                    end
                end     
            % 计算服务器定位计算
               if isStartLoc == 1
                  [StructData,UI_printInf,flag_Qin,flag_isUsed]=computerserviceFromalMain(Mix2frameforloc,parameterMoveState_cell,parameterScatter_cell,isMovetargetModel); 
                  isStartLoc = 0;
                  % 消息输出        待验证问题: 实时联调消息叠加到一定数量，有可能会影响程序速率
                  if isMovetargetModel~=1
                     inf0 = Mix2frameforloc;  
                  else 
                     inf0 = Mix2frameforloc{app.MainFrameInState_num}; 
                  end
                  
                  app.outPut_Message(' ');
                  app.outPut_Message('混合数据帧信息...');
                  str_time=sprintf('  帧发送时间:%d-%d-%d-%d-%d-%d-%d',inf0{1,5},inf0{1,6}, ...
                      inf0{1,7},inf0{1,8},inf0{1,9},inf0{1,10},inf0{1,11});
                  app.outPut_Message(str_time);
                  str_lbh=sprintf('  站址经度：东经%0.3f 纬度：北纬%0.3f 高度：海平面以上%0.3f米',inf0{1,12},inf0{1,13},inf0{1,14});
                  app.outPut_Message(str_lbh);
                  str_mag=sprintf('  站址x轴磁场分量：%0.3f y轴磁场分量：%0.3f z轴磁场分量：%0.3f',inf0{1,17},inf0{1,18},inf0{1,19});
                  app.outPut_Message(str_mag);
                  
                  app.outPut_Message(' ');
                  app.outPut_Message('情景定位信息...');
                  if flag_Qin ==1
                      pufengsousuo_info = UI_printInf{1};
                      app.outPut_Message(pufengsousuo_info);
                      Process_M = load('E:\混合数据帧2解析\情景1中间结果存储\Process_M.mat').Process_M;
                      if Process_M >=app.MainState_num
                          pufengsousuo_info1 = UI_printInf{2};
                          pufengsousuo_info2 = UI_printInf{3};
                          app.outPut_Message(pufengsousuo_info1);
                          app.outPut_Message('  情景1: 对测向交汇定位结果开始进行聚类...');
                          app.outPut_Message(pufengsousuo_info2);
                      end
                  elseif flag_Qin ==2
                      pufengsousuo_info = UI_printInf{2};
                      str = sprintf('  情景2定位结果：%d组定位结果！',size(StructData{11},2));
                      app.outPut_Message(pufengsousuo_info);
                      app.outPut_Message(str);
                  elseif flag_Qin ==3
                      branch1_info = UI_printInf{2};
                      branch2_info = UI_printInf{5};
                      branch3_info = UI_printInf{8};
                      str = sprintf('  情景3定位结果：%d组定位结果！',size(StructData{11},2));
                      if size(branch1_info,2)>0  ,app.outPut_Message(branch1_info); end
                      if size(branch2_info,2)>0  ,app.outPut_Message(branch2_info); end
                      if size(branch3_info,2)>0  ,app.outPut_Message(branch3_info); end
                      app.outPut_Message(str);
                  end        
            % 计算服务器点迹分选
               if isMovetargetModel ~=1
                  isstartTrace = 1;        % 情景2/3 
               elseif isMovetargetModel ==1 && Process_M >= app.MainState_num
                  isstartTrace = 1;        % 情景1 且处理站数符合预先设置
               else
                  isstartTrace = 0;
               end
               if isstartTrace ==1
                   if flag_isUsed ==0
                       StructData{11} = [0,0,0]';
                   end
                   app.Pointtrace_num = app.Pointtrace_num+1;
                   if app.CPI_num ~=0
                       app.CPI_num = app.Pointtrace_num;
                       app.Pointtrace_cell = cell(1,1);
                       app.Pointtrace_cell{1} = StructData{11};     % 将当前帧直接送入到分选函数中（此步骤只会发生在第一次点迹分选之后）
                   else
                       app.Pointtrace_cell{app.Pointtrace_num} = StructData{11};
                   end
                   app.outPut_Message(' ');
                   app.outPut_Message('点迹分选信息...');
                   Process_M = 0;
                   save('E:\混合数据帧2解析\情景1中间结果存储\Process_M.mat','Process_M');
               end
               if app.Pointtrace_num >=app.MainFrameTrace_num
                   app.outPut_Message('  进入点迹分选...');
                   Loc_cell = app.Pointtrace_cell;
                   Vmin = app.param_bomen(1);
                   Vmax = app.param_bomen(2);
                   Vz_min = app.param_bomen(3);
                   Vz_max = app.param_bomen(4);
                   detax = app.param_bomen(5);  detay = app.param_bomen(6); detaz = app.param_bomen(7);
                   detaT=1;  GRADE_MAX=2; K=app.MainFrameTrace_num;
                   [XYZ,Target_V, ~,~]=Qin_LocByTraceOfPointFormal(Loc_cell,app.CPI_num,Vmin,Vmax,Vz_min,Vz_max,detax,detay,detaz,detaT,GRADE_MAX,K);
                   if app.CPI_num ==0
                       app.CPI_num = app.Pointtrace_num;
                   end
                   str = sprintf("xyz:%d个",size(XYZ,2));
                   fprintf(str)
                   % 回传数据
                   LastCPI_Struct = StructData;
                   MutilTargetLocResult =app.constructTraceResult(LastCPI_Struct,XYZ,Target_V);
                   app.returnTraceResult(tcp,MutilTargetLocResult);
                   %
               else
                   str = sprintf('  等待%d帧数据...',app.MainFrameTrace_num-app.Pointtrace_num);
                   app.outPut_Message(str);
               end
               pause(0.001);
              end
            end
            stop(tim);
            delete(tim);
          else
              app.outPut_Message("连接失败，请检查监视服务器IP地址！")
          end
          fclose(tcp);
          delete(tcp);
          app.outPut_Message("断开连接！");
          app.Button_connect.Value=0;
          app.CONNECTBREAK = 0;
        end

        % Value changed function: Button_clearmessage
        function Button_clearmessageValueChanged(app, event)
            app.Button_clearmessage.Value = 0;
            app.Text_mainMessage.Value ='';
            app.mainMessage = sprintf('%s\n', '***定位过程信息提示***');
        end

        % Value changed function: ScatterCheckBox
        function ScatterCheckBoxValueChanged(app, event)
            value = app.ScatterCheckBox.Value;
            if value ==1
                app.DOACheckBox.Value = 0;
                app.isSetLocModel = 1;
                app.DropDown_Pf.Enable= 'on';
                app.DropNumSignalState.Enable= 'off';
                app.DropNumFrameInState.Enable = 'off';
            else
               app.isSetLocModel = 0; 
               app.DropNumSignalState.Enable= 'on';
               app.DropNumFrameInState.Enable = 'on';
            end
        end

        % Value changed function: DOACheckBox
        function DOACheckBoxValueChanged(app, event)
            value = app.DOACheckBox.Value;
            if value ==1
                app.ScatterCheckBox.Value = 0;
                app.isSetLocModel = 1;
                app.DropDown_Pf.Enable= false;
                app.DropNumSignalState.Enable= true;
                app.DropNumFrameInState.Enable = true;
            else
                app.isSetLocModel = 0;
                app.DropDown_Pf.Enable= true;
            end
        end

        % Value changed function: Button_set
        function Button_setValueChanged(app, event)
          app.Button_set.Value = 0;
          if app.isSetLocModel ==0
              app.outPut_Message('请先选择目标定位方式!');
              app.isSetButton = 0;
              return ;
          end
          if app.isSetBomen ==0
              app.outPut_Message('请设置波门参数!');
              return ;
          end
          if app.Button_connect.Value ==1
              app.outPut_Message('无法设置，请先断开连接!');
              return ;
          end
          app.MainIp = app.IPdisplay.Value;
          assignin("base","Ip",app.MainIp);
          app.MainPort = app.Portdisplay.Value;
          assignin("base","Port",app.MainPort);
          if app.ScatterCheckBox.Value == 1
            app.MainPf = str2double(app.DropDown_Pf.Value);
            assignin("base","Pf",app.MainPf);
            msg_set = sprintf("监视服务器Ip:%s Port:%d  定位方式:升空散射体辅助定位 pf=%f",app.MainIp ...
                                                                ,app.MainPort,app.MainPf);
          else
            app.MainFrameInState_num = str2double(app.DropNumFrameInState.Value);
            app.MainState_num = str2double(app.DropNumSignalState.Value);
            assignin("base","State_num",app.MainFrameInState_num);
            msg_set = sprintf("监视服务器Ip:%s Port:%d  定位方式:移动单站纯DOA定位 \n站数=%0.1f 站内帧数=%0.1f",app.MainIp ...
                                                                ,app.MainPort,app.MainState_num,app.MainFrameInState_num);
          end
          app.MainFrameTrace_num = str2double(app.DropFramenumForTrace.Value);
          app.isSetButton = 1;
          app.outPut_Message(msg_set);
          app.initQinParameter();
          app.initalQin1
        end

        % Button pushed function: Button_test
        function Button_testPushed(app, event)
            % 测试按钮:在不与接收服务器通信的情况下测试定位主函数
            if app.isSetButton == 0
                app.Button_connect.Value = 0;
                app.outPut_Message("请先设置定位方式和参数！");
                return ;
            end
            
            % 参数
            isStartLoc = 0;
            isMovetargetModel =app.DOACheckBox.Value;
            parameterMoveState_cell = cell(1,7);                           %  站数、站内帧数
            parameterScatter_cell = cell(1,2);                             %  虚警率、散射体是否移动
            app.Pointtrace_cell = cell(1,app.DropFramenumForTrace.Value);
            if app.ScatterCheckBox.Value ==1
                parameterScatter_cell{1} = app.MainPf;
                parameterScatter_cell{2} = 0;                              %  0 设置flag_yundong=0
            elseif app.DOACheckBox.Value == 1
                parameterMoveState_cell{1} = app.MainState_num;
                parameterMoveState_cell{2} = app.MainFrameInState_num;
                ya = 5.5;
                yb = 8.5;
                r=6.5;
                k_up =0.55;
                k_down = 0.3;
                parameterMoveState_cell{3} = ya;
                parameterMoveState_cell{4} = yb;
                parameterMoveState_cell{5} = r;
                parameterMoveState_cell{6} = k_up;
                parameterMoveState_cell{7} = k_down;
            end
            
            % 获取解析好的混合数据帧2
            app.isSetButton = 0;
            app.FrameNumInPool = app.FrameNumInPool+1;
            Poolindex = app.FrameNumInPool;
            
%           Mixframe2_struct = load('E:\混合数据帧2解析\Mixframe2_Qin3-S3-T1-序号1_decode.mat');
            Mixframe2_struct = load('E:\混合数据帧2解析\Mixframe2_FormQin2-S2-T1-序号1_decode');
           
            if isMovetargetModel~=1
             app.FramePool{Poolindex} = Mixframe2_struct.Struct_data;
            else
              app.FramePool{1} = load('E:\混合数据帧2解析\定位情景1帧解析\Mixframe2_Qin1-站1-S2-T1-序号1_decode.mat').Struct_data;
              app.FramePool{2} = load('E:\混合数据帧2解析\定位情景1帧解析\Mixframe2_Qin1-站1-S2-T1-序号2_decode.mat').Struct_data;
              app.FramePool{3} = load('E:\混合数据帧2解析\定位情景1帧解析\Mixframe2_Qin1-站1-S2-T1-序号3_decode.mat').Struct_data;
              app.FramePool{4} = load('E:\混合数据帧2解析\定位情景1帧解析\Mixframe2_Qin1-站2-S2-T1-序号1_decode.mat').Struct_data;
              app.FramePool{5} = load('E:\混合数据帧2解析\定位情景1帧解析\Mixframe2_Qin1-站2-S2-T1-序号2_decode.mat').Struct_data;
              app.FramePool{6} = load('E:\混合数据帧2解析\定位情景1帧解析\Mixframe2_Qin1-站2-S2-T1-序号3_decode.mat').Struct_data;
              app.FramePool{7} = load('E:\混合数据帧2解析\定位情景1帧解析\Mixframe2_Qin1-站3-S2-T1-序号1_decode.mat').Struct_data;
              app.FramePool{8} = load('E:\混合数据帧2解析\定位情景1帧解析\Mixframe2_Qin1-站3-S2-T1-序号2_decode.mat').Struct_data;
              app.FramePool{9} = load('E:\混合数据帧2解析\定位情景1帧解析\Mixframe2_Qin1-站3-S2-T1-序号3_decode.mat').Struct_data;
              app.FrameNumInPool = 9;
            end
            Mix2frame_num = app.FrameNumInPool;
            
            if (Mix2frame_num>0 && isMovetargetModel ~=1)
                Mix2frameforloc = app.FramePool{1};
                app.FramePool(1) = [];                             % 读取完直接从frame的池子中删除
                app.FrameNumInPool = app.FrameNumInPool-1;
                isStartLoc =1;
            elseif isMovetargetModel ==1 && Mix2frame_num>=app.MainFrameInState_num
                Process_M = load('E:\混合数据帧2解析\情景1中间结果存储\Process_M.mat').Process_M;
                isAvailable = app.isAvailableStation(Process_M,app.FramePool);
                if isAvailable==0 ,isStartLoc =0; end
                if isAvailable==1 
                    isStartLoc =1;
                    Mix2frameforloc = cell(1,app.MainFrameInState_num);
                    for i=app.MainFrameInState_num:-1:1
                        Mix2frameforloc{i} = app.FramePool{i};
                        app.FramePool(i) = [];                             % 读取完直接从frame的池子中删除
                        app.FrameNumInPool = app.FrameNumInPool-1;
                    end
                end
            end
            
           if isStartLoc ==1
            % 计算服务器定位计算
            [StructData,UI_printInf,flag_Qin,flag_isUsed]=computerserviceFromalMain(Mix2frameforloc,parameterMoveState_cell,parameterScatter_cell,isMovetargetModel);
            assignin("base","UI_printInf",UI_printInf);
            % 消息输出        待验证问题: 实时联调消息叠加到一定数量，有可能会影响程序速率
            inf0 = Mix2frameforloc;
            app.outPut_Message('混合数据帧信息...');
            str_time=sprintf('  帧发送时间:%d-%d-%d-%d-%d-%d-%d',inf0{1,5},inf0{1,6}, ...
                inf0{1,7},inf0{1,8},inf0{1,9},inf0{1,10},inf0{1,11});
            app.outPut_Message(str_time);
            str_lbh=sprintf('  站址经度：东经%f 纬度：北纬%f 高度：海平面以上%f米',inf0{1,12},inf0{1,13},inf0{1,14});
            app.outPut_Message(str_lbh);
            str_mag=sprintf('  站址x轴磁场分量：%f y轴磁场分量：%f z轴磁场分量：%f',inf0{1,17},inf0{1,18},inf0{1,19});
            app.outPut_Message(str_mag);
            
            app.outPut_Message('情景定位信息...');
            if flag_Qin ==1
                pufengsousuo_info = UI_printInf{1};
                app.outPut_Message(pufengsousuo_info);
            elseif flag_Qin ==2
                pufengsousuo_info = UI_printInf{2};
                str = sprintf('  情景2定位结果：%d组定位结果！',size(StructData{11},2));
                app.outPut_Message(pufengsousuo_info);
                app.outPut_Message(str);
            elseif flag_Qin ==3
                branch1_info = UI_printInf{2};
                branch2_info = UI_printInf{5};
                branch3_info = UI_printInf{8};
                str = sprintf('  情景3定位结果：%d组定位结果！',size(StructData{11},2));
                if size(branch1_info,2)>0  ,app.outPut_Message(branch1_info); end
                if size(branch2_info,2)>0  ,app.outPut_Message(branch2_info); end
                if size(branch3_info,2)>0  ,app.outPut_Message(branch3_info); end
                app.outPut_Message(str);
            end
            
            % 计算服务器点迹分选
            app.outPut_Message('点迹分选与滤波信息...');
            if flag_isUsed ~=0
                app.Pointtrace_num = app.Pointtrace_num+1;
                app.Pointtrace_cell{app.Pointtrace_num} = StructData;
            end
            if app.Pointtrace_num >=app.MainFrameTrace_num
                app.outPut_Message('  进入点迹分选...');
                
            else
                str = sprintf('  等待%d帧数据...',app.MainFrameTrace_num-app.Pointtrace_num);
                app.outPut_Message(str);
            end
          end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            ComSer2Formal_bomen(app);
            
        end

        % Value changed function: connectBreak
        function connectBreakValueChanged(app, event)
            value = app.connectBreak.Value;
            
            if app.Button_connect.Value == 1
              app.CONNECTBREAK = 1;
            else
               app.outPut_Message('请先连接服务器！');
            end
            
            app.connectBreak.Value = 0;
        end

        % Value changed function: DropNumSignalState
        function DropNumSignalStateValueChanged(app, event)
            value = app.DropNumSignalState.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '监视服务器连接';
            app.IPLabel = uilabel(app.Panel);
            app.IPLabel.Text = '监视服务器IP:';
            app.IPdisplay = uieditfield(app.Panel, 'text');
            app.IPdisplay.Value = '192.168.0.50';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '端口号:';
            app.Portdisplay = uieditfield(app.Panel, 'numeric');
            app.Portdisplay.Value = 8088;
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = '定位方式与参数设置';
            app.Label_8 = uilabel(app.Panel_2);
            app.Label_8.Text = '定位方式:';
            app.ScatterCheckBox = uicheckbox(app.Panel_2);
            app.ScatterCheckBox.ValueChangedFcn = createCallbackFcn(app, @ScatterCheckBoxValueChanged, true);
            app.ScatterCheckBox.Text = '升空散射体辅助定位';
            app.DOACheckBox = uicheckbox(app.Panel_2);
            app.DOACheckBox.ValueChangedFcn = createCallbackFcn(app, @DOACheckBoxValueChanged, true);
            app.DOACheckBox.Text = '移动单站纯DOA定位';
            app.Label_9 = uilabel(app.Panel_2);
            app.Label_9.Text = '参数设置:';
            app.Label_3 = uilabel(app.Panel_2);
            app.Label_3.Text = '虚警率';
            app.DropDown_Pf = uidropdown(app.Panel_2);
            app.DropDown_Pf.Items = {'1e-4', '1e-3', '1e-2', ''};
            app.DropDown_Pf.Value = '1e-4';
            app.Label_10 = uilabel(app.Panel_2);
            app.Label_10.Text = '(方式1)';
            app.Label_11 = uilabel(app.Panel_2);
            app.Label_11.Text = '(方式2)';
            app.Label_4 = uilabel(app.Panel_2);
            app.Label_4.Text = '定位站数 ';
            app.DropNumSignalState = uidropdown(app.Panel_2);
            app.DropNumSignalState.Items = {'1', '2', '3', '4'};
            app.DropNumSignalState.ValueChangedFcn = createCallbackFcn(app, @DropNumSignalStateValueChanged, true);
            app.DropNumSignalState.Value = '2';
            app.Label_5 = uilabel(app.Panel_2);
            app.Label_5.Text = '站内帧数';
            app.DropNumFrameInState = uidropdown(app.Panel_2);
            app.DropNumFrameInState.Items = {'2', '3', '4', '5'};
            app.DropNumFrameInState.Value = '3';
            app.Label_12 = uilabel(app.Panel_2);
            app.Label_12.Text = '点迹分选:';
            app.Label_7 = uilabel(app.Panel_2);
            app.Label_7.Text = '最小帧数 ';
            app.DropFramenumForTrace = uidropdown(app.Panel_2);
            app.DropFramenumForTrace.Items = {'2', '3', '4', '5'};
            app.DropFramenumForTrace.Value = '2';
            app.Button_set = uibutton(app.Panel_2, 'state');
            app.Button_set.ValueChangedFcn = createCallbackFcn(app, @Button_setValueChanged, true);
            app.Button_set.Text = '设置';
            app.Button = uibutton(app.Panel_2, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '波门参数';
            app.Button_connect = uibutton(app.UIFigure, 'state');
            app.Button_connect.ValueChangedFcn = createCallbackFcn(app, @Button_connectValueChanged, true);
            app.Button_connect.Text = '连接服务器';
            app.Label_13 = uilabel(app.UIFigure);
            app.Label_13.Text = '消息打印';
            app.Text_mainMessage = uitextarea(app.UIFigure);
            app.Text_mainMessage.Editable = 'off';
            app.Button_clearmessage = uibutton(app.UIFigure, 'state');
            app.Button_clearmessage.ValueChangedFcn = createCallbackFcn(app, @Button_clearmessageValueChanged, true);
            app.Button_clearmessage.Text = '清除';
            app.Button_test = uibutton(app.UIFigure, 'push');
            app.Button_test.ButtonPushedFcn = createCallbackFcn(app, @Button_testPushed, true);
            app.Button_test.Text = '测试按钮';
            app.connectBreak = uibutton(app.UIFigure, 'state');
            app.connectBreak.ValueChangedFcn = createCallbackFcn(app, @connectBreakValueChanged, true);
            app.connectBreak.Text = '断开';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ComSer2Formal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end