classdef ComSer2Formal_bomen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Panel_set_tracepoint_parameter  matlab.ui.container.Panel
        VminEditFieldLabel              matlab.ui.control.Label
        VminEditField                   matlab.ui.control.NumericEditField
        VmaxEditFieldLabel              matlab.ui.control.Label
        VmaxEditField                   matlab.ui.control.NumericEditField
        VzminEditFieldLabel             matlab.ui.control.Label
        VzminEditField                  matlab.ui.control.NumericEditField
        VzmaxEditFieldLabel             matlab.ui.control.Label
        VzmaxEditField                  matlab.ui.control.NumericEditField
        xEditFieldLabel                 matlab.ui.control.Label
        xEditField                      matlab.ui.control.NumericEditField
        yEditFieldLabel                 matlab.ui.control.Label
        yEditField                      matlab.ui.control.NumericEditField
        zEditFieldLabel                 matlab.ui.control.Label
        zEditField                      matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        Button                          matlab.ui.control.Button
    end

    
    properties (Access = public)
        MainUI % Description
    end
    
    methods (Access = public)
        
        function transparam_bomen(app,v_min,v_max,vzmin,vzmax,x,y,z)
           app.MainUI.param_bomen(1,1) = v_min;
           app.MainUI.param_bomen(1,2) = v_max;
           app.MainUI.param_bomen(1,3) = vzmin;
           app.MainUI.param_bomen(1,4) = vzmax;
           app.MainUI.param_bomen(1,5) = x;
           app.MainUI.param_bomen(1,6) = y;
           app.MainUI.param_bomen(1,7) = z;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainui)
            app.UIFigure.Name = "波门参数设置";
            app.MainUI = mainui;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            V_min = app.VminEditField.Value;
            V_max = app.VmaxEditField.Value;
            Vzmin = app.VzminEditField.Value;
            Vzmax = app.VzmaxEditField.Value;
               x  = app.xEditField.Value;
               y  = app.yEditField.Value;
               z  = app.zEditField.Value;
            transparam_bomen(app,V_min,V_max,Vzmin,Vzmax,x,y,z);
            app.MainUI.isSetBomen = 1;
            close(app.UIFigure);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel_set_tracepoint_parameter = uipanel(app.UIFigure);
            app.Panel_set_tracepoint_parameter.Title = '波门参数设置';
            app.VminEditFieldLabel = uilabel(app.Panel_set_tracepoint_parameter);
            app.VminEditFieldLabel.Text = 'Vmin';
            app.VminEditField = uieditfield(app.Panel_set_tracepoint_parameter, 'numeric');
            app.VmaxEditFieldLabel = uilabel(app.Panel_set_tracepoint_parameter);
            app.VmaxEditFieldLabel.Text = 'Vmax';
            app.VmaxEditField = uieditfield(app.Panel_set_tracepoint_parameter, 'numeric');
            app.VmaxEditField.Value = 1;
            app.VzminEditFieldLabel = uilabel(app.Panel_set_tracepoint_parameter);
            app.VzminEditFieldLabel.Text = 'Vzmin';
            app.VzminEditField = uieditfield(app.Panel_set_tracepoint_parameter, 'numeric');
            app.VzmaxEditFieldLabel = uilabel(app.Panel_set_tracepoint_parameter);
            app.VzmaxEditFieldLabel.Text = 'Vzmax';
            app.VzmaxEditField = uieditfield(app.Panel_set_tracepoint_parameter, 'numeric');
            app.VzmaxEditField.Value = 1;
            app.xEditFieldLabel = uilabel(app.Panel_set_tracepoint_parameter);
            app.xEditFieldLabel.Text = 'x坐标波门';
            app.xEditField = uieditfield(app.Panel_set_tracepoint_parameter, 'numeric');
            app.xEditField.Value = 1;
            app.yEditFieldLabel = uilabel(app.Panel_set_tracepoint_parameter);
            app.yEditFieldLabel.Text = 'y坐标波门';
            app.yEditField = uieditfield(app.Panel_set_tracepoint_parameter, 'numeric');
            app.yEditField.Value = 2;
            app.zEditFieldLabel = uilabel(app.Panel_set_tracepoint_parameter);
            app.zEditFieldLabel.Text = 'z坐标波门';
            app.zEditField = uieditfield(app.Panel_set_tracepoint_parameter, 'numeric');
            app.zEditField.Value = 3;
            app.Label = uilabel(app.Panel_set_tracepoint_parameter);
            app.Label.Text = '参数说明';
            app.TextArea = uitextarea(app.Panel_set_tracepoint_parameter);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'初始波门参数：'; '   Vmin：水平运动最小速度'; '  Vmax：水平运动最大速度'; '  Vzmin：升降运动最小速度'; ' Vzmax：升降运动最大速度'; ''; '后续波门参数：'; '    x： x轴可接受误差范围'; '    y： y轴可接受误差范围'; '     z： z轴可接受误差范围'};
            app.Button = uibutton(app.Panel_set_tracepoint_parameter, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '确定';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ComSer2Formal_bomen(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end